classdef HORUS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainScreen                      matlab.ui.container.Panel
        MainGrid                        matlab.ui.container.GridLayout
        PasswordHTML                    matlab.ui.control.HTML
        UserNameHTML                    matlab.ui.control.HTML
        ConectionCableCheckBox          matlab.ui.control.CheckBox
        AccederHTML                     matlab.ui.control.HTML
        CrearCuentaLink                 matlab.ui.control.Hyperlink
        SignInStateLBL                  matlab.ui.control.Label
        LOGOSEPA                        matlab.ui.control.Image
        SignInLBL                       matlab.ui.control.Label
        AnalisisScreen                  matlab.ui.container.Panel
        AnalisisGrid                    matlab.ui.container.GridLayout
        ExportHTML                      matlab.ui.control.HTML
        FrequencyLBL                    matlab.ui.control.Label
        DurationLBL                     matlab.ui.control.Label
        TimeLBL                         matlab.ui.control.Label
        FrecuenciaLabel                 matlab.ui.control.Label
        ObservacionesPanel              matlab.ui.container.Panel
        GridLayout_5                    matlab.ui.container.GridLayout
        ObsHTML                         matlab.ui.control.HTML
        AsimetriaIzquierdaDerechaPanel  matlab.ui.container.Panel
        GridLayout_4                    matlab.ui.container.GridLayout
        AsimetriaAxes                   matlab.ui.control.UIAxes
        MetricasclavePanel              matlab.ui.container.Panel
        GridLayout_3                    matlab.ui.container.GridLayout
        CorrelationButton               matlab.ui.control.Button
        AsimetriaButton                 matlab.ui.control.Button
        FijacionMediaButton             matlab.ui.control.Button
        ParpadeosButton                 matlab.ui.control.Button
        DurationmediaButton             matlab.ui.control.Button
        SacadastotalesButton            matlab.ui.control.Button
        TasadeparpadeoPanel             matlab.ui.container.Panel
        GridLayout_2                    matlab.ui.container.GridLayout
        TotalParpadeosLBL               matlab.ui.control.Label
        RateParpadeosLBL                matlab.ui.control.Label
        ParpadeostotalesLabel           matlab.ui.control.Label
        ParpadeosminLabel               matlab.ui.control.Label
        Gauge                           matlab.ui.control.SemicircularGauge
        CorrelacindeSpearmanPanel       matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        RresultLBL                      matlab.ui.control.Label
        CorrAxes                        matlab.ui.control.UIAxes
        DuracionLabel                   matlab.ui.control.Label
        FechaLabel                      matlab.ui.control.Label
        AnalisisEventosHTML             matlab.ui.control.HTML
        BackAnalisisHTML                matlab.ui.control.HTML
        Image_2                         matlab.ui.control.Image
        ANALISISEOGLabel                matlab.ui.control.Label
        ConectScreen                    matlab.ui.container.Panel
        ConectGrid                      matlab.ui.container.GridLayout
        BackConectHTML                  matlab.ui.control.HTML
        TomarMedidasHTML                matlab.ui.control.HTML
        BuscarDevicesHTML               matlab.ui.control.HTML
        LogoHorusHTML                   matlab.ui.control.HTML
        DevicesListHTML                 matlab.ui.control.HTML
        WifiStatusHTML                  matlab.ui.control.HTML
        ShowHiddenWifisCheckBox         matlab.ui.control.CheckBox
        AnalisisSignalsScreen           matlab.ui.container.Panel
        AnalisisSignalsGrid             matlab.ui.container.GridLayout
        BackAnalisisSignalsHTML         matlab.ui.control.HTML
        ResultadosAnalisisHTML          matlab.ui.control.HTML
        VerticalRExpandedHTML           matlab.ui.control.HTML
        VerticalLExpandedHTML           matlab.ui.control.HTML
        HorizontalRExpandedHTML         matlab.ui.control.HTML
        HorizontalLExpandedHTML         matlab.ui.control.HTML
        EYEL2                           matlab.ui.control.Image
        EYER2                           matlab.ui.control.Image
        LVerticalAxis                   matlab.ui.control.UIAxes
        LHorizontalAxis                 matlab.ui.control.UIAxes
        RVerticalAxis                   matlab.ui.control.UIAxes
        RHorizontalAxis                 matlab.ui.control.UIAxes
        ExpandedViewScreen              matlab.ui.container.Panel
        ExpandedViewGrid                matlab.ui.container.GridLayout
        SignalViewSlider                matlab.ui.control.Slider
        SignalViewLabel                 matlab.ui.control.Label
        BackExpandedViewHTML            matlab.ui.control.HTML
        ExpandedPlot                    matlab.ui.control.UIAxes
        GraficasScreen                  matlab.ui.container.Panel
        GraficasGrid                    matlab.ui.container.GridLayout
        StartStopHTML                   matlab.ui.control.HTML
        FSLabel                         matlab.ui.control.Label
        BackGraficasHTML                matlab.ui.control.HTML
        AnalizarMedidasHTML             matlab.ui.control.HTML
        EYEL                            matlab.ui.control.Image
        EYER                            matlab.ui.control.Image
        ConectedLampGrid                matlab.ui.container.GridLayout
        ConectedLamp                    matlab.ui.control.Lamp
        ConectedLBL                     matlab.ui.control.Label
        VEOR                            matlab.ui.control.UIAxes
        HEOL                            matlab.ui.control.UIAxes
        HEOR                            matlab.ui.control.UIAxes
        VEOL                            matlab.ui.control.UIAxes
        EventsAnalisisScreen            matlab.ui.container.Panel
        EventsAnalisisGrid              matlab.ui.container.GridLayout
        BackEventsAnalisisHTML          matlab.ui.control.HTML
        EventsTable                     matlab.ui.control.Table
        EventsAnalisisLBL               matlab.ui.control.Label
        ChooseActionScreen              matlab.ui.container.Panel
        ChooseActionGrid                matlab.ui.container.GridLayout
        BackChooseActionHTML            matlab.ui.control.HTML
        ConectarGafasHTML               matlab.ui.control.HTML
        VerAnalisisPreviosHTML          matlab.ui.control.HTML
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
    end


    properties (Access = private)
        usuario
        password
        AvailableWifis
        DevicesWifis
        WifiInterface
        PasswordOptions = ["zeri3333","wifi.ROS1","00000000","12345678"];
        Device
        SHEOL
        SVEOL
        SHEOR
        SVEOR
        ColorSHEOR = '#00b4d8'
        ColorSVEOL = '#d62828'
        ColorSHEOL = '#6a0dad'
        ColorSVEOR = '#2dc653'
        LineSHEOL = []
        LineSVEOL = []
        LineSHEOR = []
        LineSVEOR = []
        NoDataCount     % contador de intentos sin datos
        TimerGraficar        % timer de adquisición
        counter               % contador de muestras
        lastTime
        fs_est
        fs_fixed
        fs_initial = 250.00
        fs_calibrating = true
        fs_startTime
        FilterState
        SHEOL_f              % H EOL filtered signal (mV)
        SVEOL_f              % V EOL filtered signal (mV)
        SHEOR_f              % H EOR filtered signal (mV)
        SVEOR_f              % V EOR filtered signal (mV)
        SignalsFiltered_OK = false   % flag: true if post filtering was successful
        counter_fs = 0
        yMaxSticky = 50   % valor inicial mínimo en mV (evita escala 0 al inicio)
        % Señales globales para análisis
        HEOG_global
        VEOG_global

        % Resultados de sacadas
        SaccadesMetrics
        StartAppearance = struct( ...
            'bgColor',      '#b8f0c8', ...
            'textColor',    '#1a4a2e', ...
            'borderColor',  '#6dcea0', ...
            'borderWidth',  '2px',     ...
            'borderRadius', '14px',    ...
            'label',        'Start'    ...
            )
        StopAppearance = struct( ...
            'bgColor',      '#f7c1c1', ...
            'textColor',    '#6b1a1a', ...
            'borderColor',  '#d47a7a', ...
            'borderWidth',  '2px',     ...
            'borderRadius', '14px',    ...
            'label',        'Stop'     ...
            )
        ExpandedSignalTime
        ExpandedViewWindow    = 12      % segundos visibles a la vez
        crearcuenta = false
    end

    methods (Access = private)
        % Funcion para cambiar de ventana
        function ChangeWindowTo(app,screen)
            screens = [...
                app.MainScreen,...
                app.ChooseActionScreen,...
                app.ConectScreen,...
                app.GraficasScreen,...
                app.AnalisisScreen,...
                app.EventsAnalisisScreen,...
                app.AnalisisSignalsScreen,...
                app.ExpandedViewScreen
                ];
            for a = screens
                a.Visible = 'off';
            end
            screen.Visible = 'on';
        end
        % Funcion para prepararse a la conexion de wifi
        function PrepareWifi(app)
            wifiName = "";
            if ispc

                % detectar adaptador wifi (incluso deshabilitado)
                cmd = ['powershell -Command "', ...
                    'Get-NetAdapter | Where-Object {$_.NdisPhysicalMedium -eq 9} | Select-Object -First 1 -ExpandProperty Name"'];

                [~,out] = system(cmd);
                wifiName = strtrim(out);

                if wifiName == ""
                    error("No se encontró adaptador WiFi");
                end
                % comprobar estado
                [~,interfaces] = system('netsh interface show interface');

                if ~contains(interfaces,"Enabled")

                    cmd = sprintf('netsh interface set interface "%s" admin=enabled',wifiName);
                    system(cmd);

                end
                % desconectar wifi actual
                system('netsh wlan disconnect');
            else
                app.WifiInterface = wifiName;
                error("Sistema operativo no soportado")
            end
            app.WifiInterface = wifiName;
        end
        % Funcion para hacer un display de las redes wifi disponibles
        function FindAvailableWifis(app)
            app.AvailableWifis = strings(0);
            ssids = strings(0);

            if ~ispc
                error("Sistema operativo no soportado")
            end

            % Disable/enable para forzar rescan real
            psCommand = sprintf([...
                '$wifi = Get-NetAdapter -Name "%s"; ' ...
                'Disable-NetAdapter -Name $wifi.Name -Confirm:$false; ' ...
                'Start-Sleep -Seconds 2; ' ...
                'Enable-NetAdapter -Name $wifi.Name -Confirm:$false;'], app.WifiInterface);

            [status, out] = system(['powershell -Command "', psCommand, '"']);
            if status ~= 0
                error("Error forzando reescaneo Wi-Fi:\n%s", out);
            end

            % Esperar a que el adaptador este habilitado
            ready = false;
            for k = 1:10
                pause(1)
                [~, state] = system('netsh interface show interface');
                lines = splitlines(state);
                wifiLine = lines(contains(lines, app.WifiInterface));
                if ~isempty(wifiLine) && contains(wifiLine, ["Enabled","Habilitado"])
                    ready = true;
                    break
                end
            end

            if ~ready
                error("La interfaz WiFi no se activó correctamente")
            end

            %Esperar scan fresco: repetir hasta que dos resultados ──
            %consecutivos sean idénticos (descarta la caché vieja)
            pause(2);   % margen mínimo antes del primer scan

            previousSSIDs = strings(0);
            stableCount   = 0;

            for attempt = 1:12   % máx ~18 s de espera
                [status, out] = system('netsh wlan show networks mode=bssid');

                if status ~= 0
                    pause(1.5);
                    continue
                end

                % Parseo robusto (cubre ES/EN, con o sin número en SSID)
                currentSSIDs = strings(0);
                lines = splitlines(out);
                for i = 1:length(lines)
                    tok = regexp(strtrim(lines{i}), ...
                        '^SSID\s*\d*\s*:\s*(.+)$', 'tokens', 'once');
                    if ~isempty(tok)
                        ssid = strtrim(tok{1});
                        % Excluir BSSIDs (tienen ":" de dirección MAC)
                        if ~isempty(ssid) && ~contains(ssid, ':')
                            currentSSIDs(end+1) = ssid;
                        end
                    end
                end
                currentSSIDs = unique(currentSSIDs);

                % Comparar con el scan anterior
                if isequal(sort(currentSSIDs), sort(previousSSIDs)) && ~isempty(currentSSIDs)
                    stableCount = stableCount + 1;
                    if stableCount >= 2   % dos scans iguales = resultado estable
                        ssids = currentSSIDs;
                        break
                    end
                else
                    stableCount   = 0;
                    previousSSIDs = currentSSIDs;
                end

                pause(1.5);
            end

            if isempty(ssids)
                ssids = previousSSIDs;   % usar lo último que se obtuvo
            end

            ssids = unique(ssids);
            app.AvailableWifis = flip(ssids(ssids ~= ""));
        end
        % Funcion para recargar lista de wifis
        function ReloadListWifis(app,listWifis)
            % Vaciamos y volvemos a cargar con data nueva
            app.DevicesListHTML.Data = {};
            app.DevicesListHTML.Data = listWifis;
        end
        function createWifiProfile(~,ssid,password)
            system("netsh wlan delete profile name=""" + ssid + """")
            WifiTemplate = fileread("wifi_template.xml");

            profile = strrep(WifiTemplate,"SSID_AQUI",ssid);
            profile = strrep(profile,"PASSWORD_AQUI",password);

            fid = fopen("wifi_temp.xml",'w');
            fprintf(fid,profile);
            fclose(fid);

            system('netsh wlan add profile filename="wifi_temp.xml" user=current');

        end
        % Funciion para cambiar el estado del wifi
        function ChangeWifiStatus (app,state)
            app.WifiStatusHTML.Data = state;
        end
        % Funcion para recargar las redes wifi
        function RefreshWifis(app)
            % Cambiamos el estado de la wifi a desconectado
            ChangeWifiStatus(app,'desconectado');
            app.TomarMedidasHTML.Visible = 'off';
            ReloadListWifis(app,{});
            LoadingWifis = uiprogressdlg(app.UIFigure, ...
                'Title', 'Buscando Dispositivos...', 'Message', 'Porfavor espere mientras se cargan los dispositivos...', 'Indeterminate', 'on');
            drawnow
            % Buscamos wifis disponibles
            FindAvailableWifis(app);
            disp(app.AvailableWifis);
            % Revisamos si se ha encontrado alguna red
            if isempty(app.AvailableWifis)
                close(LoadingWifis);
                uialert(app.UIFigure,'Compruebe que el Dispositivo este encendido','Ninguna Wifi Encontrada');
            else
                %const char* ssid = "ESP32SEPA"; // NOMBRE DE LA RED A CREAR
                %const char* password = "zeri3333";   // PASSWORD DE LA RED (MINIMO 8CHAR)
                comparador = contains(app.AvailableWifis, "ESP32", "IgnoreCase", true);
                app.DevicesWifis = app.AvailableWifis(comparador);
                if isempty(app.DevicesWifis)
                    uialert(app.UIFigure,'Compruebe que el Dispositivo este encendido o busque en Redes Escondidas','Ningun Dispositivo Encontrado','Icon','warning');
                end
                ReloadListWifis(app, app.DevicesWifis);
                close(LoadingWifis);
            end
        end
        % Funcion para realizar la conexion entre los datos que recoge la
        % ESP32 con MATLAB por una red wifi
        function DataConnection(app)
            try
                if ~isempty(app.Device)
                    delete(app.Device);
                    app.Device = [];   % limpia la conexión anterior
                    pause(0.1);
                end
                app.Device = tcpclient("192.168.4.1",5000);
                disp('Conectado a ESP32');
                app.ConectedLamp.Color = 'green';
                app.StartStopHTML.Visible = 'on';

            catch
                app.Device = [];  % devuelve vacío si falla
                app.ConectedLamp.Color = 'red';
                app.StartStopHTML.Visible = 'off';
            end
        end
        % Funcion para realizar la conexion entre los datos que recoge la
        % ESP32 con MATLAB por cable
        function DataConnectionCable(app)
            try
                if ~isempty(app.Device)
                    try delete(app.Device); catch, end
                    app.Device = [];
                    pause(0.1);
                end
                % https://es.mathworks.com/help/matlab/import_export/read-streaming-data-from-arduino.html
                % Cerramos las conexiones serialport existentes
                delete(serialportfind);
                % Guardamos los datos del serialport en "serialObj"
                app.Device = serialport("COM8",500000);
                % Definimos la propiedad Terminator para que coincida con el terminador
                % especificado en el codigo de arduino
                configureTerminator(app.Device,"CR/LF");
                % Vaciamos serialObj para limpiar datos antiguos
                flush(app.Device);
                app.ConectedLamp.Color = 'green';
                app.StartStopHTML.Visible = 'on';
            catch
                app.Device = [];  % devuelve vacío si falla
                uialert(app.UIFigure,'Compruebe que el Dispositivo este encendido','Ningun Dispositivo Conectado');
                app.ConectedLamp.Color = 'red';
                app.StartStopHTML.Visible = 'off';
            end

        end

        % Funcion para adquirir y  los datos recogidos previamente
        function DataAcquisition(app)
            ResetSignals(app);
            % Crear líneas animadas
            app.LineSHEOL = animatedline(app.HEOL,  'Color', app.ColorSHEOL);
            app.LineSVEOL = animatedline(app.VEOL,  'Color', app.ColorSVEOL);
            app.LineSHEOR = animatedline(app.HEOR,  'Color', app.ColorSHEOR);
            app.LineSVEOR = animatedline(app.VEOR,  'Color', app.ColorSVEOR);
            % YLim inicial centrado en 0, fijo
            app.yMaxSticky = 100;
            lim = [-100, 100];
            app.HEOL.YLim = lim;  app.HEOL.YLimMode = 'manual';
            app.VEOL.YLim = lim;  app.VEOL.YLimMode = 'manual';
            app.HEOR.YLim = lim;  app.HEOR.YLimMode = 'manual';
            app.VEOR.YLim = lim;  app.VEOR.YLimMode = 'manual';
            app.counter           = 0;
            app.NoDataCount = 0;
            windowSize      = 600;

            % Crear timer que ejecuta el callback cada 20ms (50Hz)
            app.TimerGraficar = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period',         0.04, ...
                'TimerFcn',       @(~,~) GraficarDatos(app, windowSize), ...
                'ErrorFcn',       @(~,~) StopAcquisition(app, 'error'));

            start(app.TimerGraficar);
        end
        function GraficarDatos(app, windowSize)
            try
                huboDatos = false;

                while app.Device.NumBytesAvailable > 0
                    data   = readline(app.Device);
                    valores = str2double(split(strtrim(data), ","));

                    if length(valores) == 4 && all(isfinite(valores))

                        huboDatos = true;   % ← marcar que llegaron datos válidos

                        s1 = valores(1);  s2 = valores(2);
                        s3 = valores(3);  s4 = valores(4);

                        S1mV = s1 * 0.80586080586;
                        S2mV = s2 * 0.80586080586;
                        S3mV = s3 * 0.80586080586;
                        S4mV = s4 * 0.80586080586;

                        if app.fs_calibrating
                            S1f = eogRealtimeFilter(app, S1mV, app.fs_initial, 1);
                            S2f = eogRealtimeFilter(app, S2mV, app.fs_initial, 2);
                            S3f = eogRealtimeFilter(app, S3mV, app.fs_initial, 3);
                            S4f = eogRealtimeFilter(app, S4mV, app.fs_initial, 4);
                        else
                            S1f = eogRealtimeFilter(app, S1mV, app.fs_fixed, 1);
                            S2f = eogRealtimeFilter(app, S2mV, app.fs_fixed, 2);
                            S3f = eogRealtimeFilter(app, S3mV, app.fs_fixed, 3);
                            S4f = eogRealtimeFilter(app, S4mV, app.fs_fixed, 4);
                        end

                        updateFS(app);
                        tickMax = max(abs([S1f, S2f, S3f, S4f]));
                        updateYLim(app, tickMax);

                        app.SHEOL(end+1) = S1mV; %cable naranja
                        app.SVEOL(end+1) = S2mV; %cable morado
                        app.SHEOR(end+1) = S3mV; %cable amarillo
                        app.SVEOR(end+1) = S4mV; %cable verde

                        app.counter = app.counter + 1;

                        addpoints(app.LineSHEOL, app.counter, S1f)
                        addpoints(app.LineSVEOL, app.counter, S2f)
                        addpoints(app.LineSHEOR, app.counter, S3f)
                        addpoints(app.LineSVEOR, app.counter, S4f)

                        if app.counter > windowSize
                            app.HEOL.XLim = [app.counter - windowSize, app.counter];
                            app.VEOL.XLim = [app.counter - windowSize, app.counter];
                            app.HEOR.XLim = [app.counter - windowSize, app.counter];
                            app.VEOR.XLim = [app.counter - windowSize, app.counter];
                        end
                    end
                end

                % Actualización visual
                if app.counter > 0
                    app.FSLabel.Text = sprintf('FS: %.1f Hz', app.fs_est);
                    drawnow limitrate
                end

                % Timeout: solo contar ticks donde no llegó NINGÚN dato válido
                if huboDatos
                    app.NoDataCount = 0;         % llegaron datos, se resetea
                else
                    app.NoDataCount = app.NoDataCount + 1;   % tick vacío, se acumula
                    if app.NoDataCount > 125     % 125 ticks × 40ms = 5 segundos
                        StopAcquisition(app, 'timeout');
                    end
                end

            catch
                StopAcquisition(app, 'error');
            end
        end

        function StopAcquisition(app, reason)

            if ~isempty(app.TimerGraficar) && isvalid(app.TimerGraficar)
                stop(app.TimerGraficar);
                delete(app.TimerGraficar);
                app.TimerGraficar = [];
            end
            app.AnalizarMedidasHTML.Visible = "on";

            switch reason
                case 'manual'
                    if ~isempty(app.SHEOL)
                        filterSignalsPost(app);
                    end

                case 'timeout'
                    uialert(app.UIFigure, 'Device stopped sending data.', ...
                        'Connection lost', 'Icon', 'warning');
                    app.ConectedLamp.Color = 'red';
                    app.Device = [];

                case 'error'
                    uialert(app.UIFigure, 'Error reading data from device.', ...
                        'Error', 'Icon', 'error');
                    app.ConectedLamp.Color = 'red';
                    app.Device = [];
            end
        end

        function y = eogRealtimeFilter(app, x, fs, ch)
            key = sprintf('ch%d', ch);

            if isempty(app.FilterState) || ~isfield(app.FilterState, key)
                [b_hp, a_hp] = butter(2, 0.2/(fs/2), 'high');
                [b_lp, a_lp] = butter(4, 10/(fs/2),  'low');
                if any(abs(roots(a_lp)) >= 1)
                    [b_lp, a_lp] = butter(2, 10/(fs/2), 'low');
                end

                app.FilterState.(key) = struct( ...
                    'b_hp',    b_hp, ...
                    'a_hp',    a_hp, ...
                    'z_hp',    zeros(2,1), ...
                    'b_lp',    b_lp, ...
                    'a_lp',    a_lp, ...
                    'z_lp',    zeros(max(length(a_lp),length(b_lp))-1, 1), ...
                    'sat_buf', x * ones(round(fs * 0.5), 1), ...
                    'vel_buf', zeros(round(fs * 0.05), 1), ... % buffer 50ms velocidad
                    'prev_y',  0);
            end

            s = app.FilterState.(key);

            % Saturación rail-to-rail → cero
            s.sat_buf = [s.sat_buf(2:end); x];
            pct_high  = mean(s.sat_buf > 3290);
            pct_low   = mean(s.sat_buf <   10);
            if pct_high > 0.5 || (pct_high + pct_low) > 0.4
                app.FilterState.(key) = s;
                y = 0;
                return
            end

            % Filtrado IIR causal
            [x_hp, s.z_hp] = filter(s.b_hp, s.a_hp, x, s.z_hp);
            [y_f,  s.z_lp] = filter(s.b_lp, s.a_lp, x_hp, s.z_lp);

            % Deadband en tiempo real: velocidad media en ventana 50ms
            vel_inst    = abs(y_f - s.prev_y) * fs;
            s.vel_buf   = [s.vel_buf(2:end); vel_inst];
            vel_mean    = mean(s.vel_buf);
            s.prev_y    = y_f;

            % Si velocidad baja Y amplitud pequeña → ruido → devolver 0
            if vel_mean < 80 && abs(y_f) < 100
                y = 0;
            else
                y = y_f;
            end

            app.FilterState.(key) = s;
        end

        function updateFS(app)
            % Inicialización
            if isempty(app.lastTime)
                app.lastTime       = tic;
                app.fs_est         = 0;
                app.fs_startTime   = tic;
                app.counter_fs     = 0;
                return;
            end

            app.counter_fs = app.counter_fs + 1;
            elapsed = toc(app.lastTime);

            % Actualizar estimación cada 0.5 segundos
            if elapsed >= 0.5
                app.fs_est   = app.counter_fs / elapsed;
                app.lastTime = tic;
                app.counter_fs = 0;
            end

            % Fijar fs tras 2 segundos de calibración
            if app.fs_calibrating
                if toc(app.fs_startTime) > 2
                    if app.fs_est > 10   % fs razonable antes de fijar
                        app.fs_fixed      = round(app.fs_est);
                        app.fs_calibrating = false;
                        app.FilterState    = struct();
                        fprintf('FS FIJADO: %.2f Hz\n', app.fs_fixed);
                    end
                end
            end
        end
        function updateYLim(app, newValue)
            absVal = abs(newValue);
            if absVal > app.yMaxSticky
                % Añadir 20% de margen y redondear para no reescalar en cada sample
                app.yMaxSticky = ceil(absVal * 1.2 / 50) * 50;
                lim = [-app.yMaxSticky, app.yMaxSticky];
                app.HEOL.YLim = lim;
                app.VEOL.YLim = lim;
                app.HEOR.YLim = lim;
                app.VEOR.YLim = lim;
            end
        end

        function filterSignalsPost(app)
            % FILTERSIGNALSPOST  v2.3  — filosofía SENSIBILIDAD PRIMERO
            %
            % Pipeline (orden de etapas por canal):
            %   0. Guard NaN/Inf en crudo          → interp1 sobre muestras válidas
            %   A. Saturación global >65%           → canal descartado (zeros)
            %   B. Blanqueo saturaciones breves     → fusión ventanas + interpolación
            %      + recovery C4=4.7µF (5τ≈5s)     → rechazo si >65% sintético
            %   C. Señal plana (electrodo muerto)   → canal descartado (zeros)
            %   D. Guard longitud mínima filtfilt   → canal descartado (zeros)
            %   E. High-pass Butterworth 0.2 Hz
            %   F. Notch IIR 50/100 Hz
            %   G. Low-pass Butterworth 10 Hz
            %   H. Validación RMS post-filtrado     → solo aviso, nunca descarta
            %
            % CAMBIOS v2.3 vs v2.2 — FILOSOFÍA SENSIBILIDAD
            % ═══════════════════════════════════════════════════════════════
            %
            % El objetivo prioritario es DETECTAR EVENTOS, no garantizar
            % señal perfecta. Un canal con 35% de señal real aún contiene
            % sacadas y parpadeos detectables; zeroearlo elimina información
            % clínica valiosa. Los umbrales de descarte se han relajado:
            %
            %   ETAPA A: saturación global  50% → 65%
            %            combinada          60% → 75%
            %     Con C4=4.7µF, saturaciones breves (<1s) dejan rail alto
            %     solo durante el transitorio; el resto de la señal es real.
            %     Subir el umbral deja pasar más canales a ETAPA B donde el
            %     blanqueo fusionado gestiona correctamente las saturaciones.
            %
            %   ETAPA B: rechazo canal      50% → 65% sintético
            %            aviso calidad      25% → 40%
            %     Si el 35% del canal es señal real y no sintética, el
            %     detector v3.2 encontrará eventos en esa porción. Con 50%
            %     se rechazaban canales que tenían 4-5 saturaciones breves
            %     con su recovery de 5s en un registro de 30s, que es una
            %     situación común con electrodos sub-óptimos.
            %
            %   ETAPA H: aviso RMS >60 mV pero NUNCA descarta el canal.
            %     Antes devolvía zeros si RMS era muy alto; ahora solo avisa.
            %     Un canal ruidoso con sacadas grandes puede tener RMS alto
            %     y aun así proveer eventos clínicamente válidos.

            app.SignalsFiltered_OK = false;

            % ═════════════════════════════════════════════════════════════════
            %  1. VALIDACIÓN DE ENTRADAS
            % ═════════════════════════════════════════════════════════════════
            raw_signals = {app.SHEOL, app.SVEOL, app.SHEOR, app.SVEOR};
            ch_names    = {'SHEOL', 'SVEOL', 'SHEOR', 'SVEOR'};

            was_empty = cellfun(@isempty, raw_signals);

            if all(was_empty)
                warning('filterSignalsPost: Todos los canales vacíos. Abortando.');
                return
            end
            if any(was_empty)
                for idx = find(was_empty)
                    warning('filterSignalsPost: %s vacío — se procesará como ceros.', ch_names{idx});
                end
            end

            if app.fs_calibrating || isempty(app.fs_fixed) || app.fs_fixed <= 0
                warning('filterSignalsPost: fs_fixed no disponible. Abortando.');
                return
            end

            fs  = app.fs_fixed;
            nyq = fs / 2;

            lens  = cellfun(@length, raw_signals(~was_empty));
            N_min = min(lens);

            for k = 1:4
                if was_empty(k)
                    raw_signals{k} = zeros(N_min, 1);
                else
                    raw_signals{k} = double(raw_signals{k}(1:N_min));
                    raw_signals{k} = raw_signals{k}(:);
                end
            end

            fprintf('\n=== POST-ACQUISITION FILTER v2.3 (HORUS) ===\n');
            fprintf('  fs_fixed : %d Hz  |  %.2f s  |  %d muestras/canal\n', ...
                fs, N_min/fs, N_min);

            % ═════════════════════════════════════════════════════════════════
            %  2. DISEÑO DE FILTROS
            % ═════════════════════════════════════════════════════════════════

            % ── High-pass 0.2 Hz ─────────────────────────────────────────────
            fc_hp = 0.2;
            Wn_hp = fc_hp / nyq;
            if Wn_hp >= 1
                fc_hp = 0.1;
                Wn_hp = fc_hp / nyq;
            end
            [b_hp, a_hp] = butter(2, Wn_hp, 'high');

            % ── Notch 50/100 Hz ──────────────────────────────────────────────
            notch_filters = {};
            for fn = [50, 100]
                if fn < nyq - 2
                    wo = fn / nyq;
                    [b_n, a_n] = iirnotch(wo, wo / 35);
                    notch_filters{end+1} = {b_n, a_n}; %#ok<AGROW>
                    fprintf('  Notch    : %.0f Hz\n', fn);
                end
            end

            % ── Low-pass 10 Hz ────────────────────────────────────────────────
            fc_lp    = min(10, nyq - 2);
            order_lp = 4;
            [b_lp, a_lp] = butter(order_lp, fc_lp / nyq, 'low');
            if any(abs(roots(a_lp)) >= 1)
                [b_lp, a_lp] = butter(2, fc_lp / nyq, 'low');
                order_lp = 2;
                fprintf('  Advertencia: LP orden 4 inestable → reducido a orden 2.\n');
            end

            fprintf('  Highpass : Butterworth 2 ord  |  fc = %.2f Hz\n', fc_hp);
            fprintf('  Lowpass  : Butterworth %d ord  |  fc = %.1f Hz\n', order_lp, fc_lp);

            % filtfilt requiere N ESTRICTAMENTE > 3 × max_filter_order
            min_filt_len = 3 * max(length(a_hp) - 1, length(a_lp) - 1);

            % ── Parámetros de blanqueo C4=4.7µF ──────────────────────────────
            C4_TAU_S      = 0.940;
            RECOVERY_SAMP = round(5.0 * C4_TAU_S * fs);   % ≈ 1292 muestras a 275 Hz

            % ── Umbral de señal plana ─────────────────────────────────────────
            adc_lsb_mV  = 3300.0 / 4095.0;
            noise_floor = max(adc_lsb_mV * 3.0, 5.0);    % ≈ 5 mV

            % ═════════════════════════════════════════════════════════════════
            %  3. PIPELINE POR CANAL
            % ═════════════════════════════════════════════════════════════════
            filtered_signals = cell(4, 1);

            for k = 1:4

                if was_empty(k)
                    filtered_signals{k} = zeros(N_min, 1);
                    fprintf('  [%s]  Canal vacío → zeros\n', ch_names{k});
                    continue
                end

                sig = raw_signals{k};

                % ══════════════════════════════════════════════════════════════
                %  ETAPA 0 — Guard NaN/Inf en crudo  (PRIMERA OPERACIÓN)
                % ══════════════════════════════════════════════════════════════
                % Si no se sanea aquí, median/rms/movmean devuelven NaN y
                % los guards de las etapas posteriores se deshabilitan en silencio.
                bad_mask = ~isfinite(sig);
                if any(bad_mask)
                    good_idx = find(~bad_mask);
                    fprintf('  [%s]  %d muestras NaN/Inf → interpolando.\n', ...
                        ch_names{k}, sum(bad_mask));
                    if length(good_idx) >= 2
                        sig(bad_mask) = interp1(good_idx, sig(good_idx), ...
                            find(bad_mask), 'linear', 'extrap');
                        sig = min(max(sig, 0), 3300);
                    else
                        filtered_signals{k} = zeros(N_min, 1);
                        fprintf('  [%s]  Canal irrecuperable (casi todo NaN/Inf) → zeros.\n', ...
                            ch_names{k});
                        continue
                    end
                end

                % ══════════════════════════════════════════════════════════════
                %  ETAPA A — Saturación global
                % ══════════════════════════════════════════════════════════════
                % v2.3: umbral elevado al 65% (era 50%).
                % Motivo: con C4=4.7µF, saturaciones breves dejan el rail alto
                % solo durante el transitorio. Un canal con 40-60% de muestras
                % saturadas puede tener el resto perfectamente válido para
                % detectar sacadas. ETAPA B gestionará el blanqueo correctamente.
                %
                % Solo se descarta si el canal está en rail de forma TAN sostenida
                % que es imposible recuperar señal útil (electrodo suelto >65% del tiempo).
                pct_high = mean(sig > 3250);
                pct_low  = mean(sig <   50);
                if pct_high > 0.65 || pct_low > 0.65 || (pct_high + pct_low) > 0.75
                    filtered_signals{k} = zeros(N_min, 1);
                    fprintf('  [%s]  Saturación global severa (H=%.0f%% L=%.0f%%) → zeros\n', ...
                        ch_names{k}, pct_high*100, pct_low*100);
                    continue
                elseif pct_high > 0.40 || pct_low > 0.40
                    fprintf('  [%s]  AVISO: saturación moderada (H=%.0f%% L=%.0f%%) — continuando.\n', ...
                        ch_names{k}, pct_high*100, pct_low*100);
                end

                % ══════════════════════════════════════════════════════════════
                %  ETAPA B — Blanqueo de saturaciones breves + recovery C4
                % ══════════════════════════════════════════════════════════════
                % Estrategia:
                %   1. Detectar regiones saturadas en señal CRUDA.
                %   2. Ampliar cada región: -10ms antes + 5τ≈4.7s después.
                %   3. FUSIONAR ventanas solapadas (evita interpolación sobre interpolación).
                %   4. Interpolar linealmente en cada ventana fusionada.
                %   5. Descartar canal si >65% sintético (era 50%).
                %
                % v2.3: umbral de rechazo 50% → 65%.
                % Con electrodo sub-óptimo, 4-5 saturaciones breves de 0.5s en 30s
                % generan ventanas de recovery de 5s cada una → 4×5s = 20s sobre 30s
                % = 67% sintético. Con el umbral en 50% ese canal se rechazaba,
                % perdiendo las sacadas del 33% restante de señal real.
                % Con 65% aún se salvan canales con señal real aprovechable.

                sat_raw = (sig > 3250) | (sig < 50);

                if any(sat_raw)
                    win_sat  = max(round(0.050 * fs), 3);
                    sat_smth = movmean(double(sat_raw), win_sat) > 0.25;

                    d_sat          = diff([0; sat_smth; 0]);
                    sat_starts_raw = find(d_sat ==  1);
                    sat_ends_raw   = find(d_sat == -1) - 1;

                    % Guard: si el suavizado elimina todos los picos aislados, no blanquear
                    if ~isempty(sat_starts_raw)

                        pre_margin   = round(0.010 * fs);
                        s_starts_ext = max(1,     sat_starts_raw - pre_margin);
                        s_ends_ext   = min(N_min, sat_ends_raw   + RECOVERY_SAMP);

                        % ── Fusionar ventanas solapadas ───────────────────────
                        m_s  = s_starts_ext(1);
                        m_e  = s_ends_ext(1);
                        ms   = zeros(length(s_starts_ext), 1);
                        me   = zeros(length(s_starts_ext), 1);
                        nM   = 0;
                        for si = 2:length(s_starts_ext)
                            if s_starts_ext(si) <= m_e
                                m_e = max(m_e, s_ends_ext(si));
                            else
                                nM     = nM + 1;
                                ms(nM) = m_s;
                                me(nM) = m_e;
                                m_s    = s_starts_ext(si);
                                m_e    = s_ends_ext(si);
                            end
                        end
                        nM     = nM + 1;
                        ms(nM) = m_s;
                        me(nM) = m_e;
                        ms = ms(1:nM);
                        me = me(1:nM);

                        total_blanked = sum(me - ms + 1);
                        pct_blanked   = total_blanked / N_min;

                        % v2.3: umbral de rechazo 65% (era 50%)
                        if pct_blanked > 0.65
                            filtered_signals{k} = zeros(N_min, 1);
                            fprintf('  [%s]  Canal descartado: %.0f%% sintético (queda muy poca señal real).\n', ...
                                ch_names{k}, pct_blanked*100);
                            fprintf('  [%s]  → Coloca el electrodo correctamente ANTES de grabar.\n', ...
                                ch_names{k});
                            continue
                        elseif pct_blanked > 0.40    % aviso a 40% (era 25%)
                            fprintf('  [%s]  AVISO: %.0f%% blankeado — calidad comprometida pero usable.\n', ...
                                ch_names{k}, pct_blanked*100);
                        end

                        % ── Interpolación por ventana fusionada ───────────────
                        for si = 1:nM
                            i1 = ms(si);
                            i2 = me(si);
                            if i1 > 1 && i2 < N_min
                                sig(i1:i2) = linspace(sig(i1-1), sig(i2+1), i2-i1+1)';
                            elseif i1 == 1 && i2 < N_min
                                sig(i1:i2) = sig(i2+1);
                            elseif i1 > 1 && i2 == N_min
                                sig(i1:i2) = sig(i1-1);
                            else
                                sig(i1:i2) = median(sig, 'omitnan');
                            end
                            fprintf('  [%s]  Sat+recovery: [%.2f – %.2f] s  (total blankeado: %.0f%%)\n', ...
                                ch_names{k}, (i1-1)/fs, (i2-1)/fs, pct_blanked*100);
                        end

                    end   % ~isempty(sat_starts_raw)
                end       % any(sat_raw)

                % ══════════════════════════════════════════════════════════════
                %  ETAPA C — Señal plana (electrodo muerto)
                % ══════════════════════════════════════════════════════════════
                % Solo descarta si la señal es COMPLETAMENTE plana después de
                % quitar el DC. Un electrodo con pobre contacto pero no muerto
                % tendrá RMS > 5 mV aunque sea ruidoso.
                sig_dc = sig - median(sig, 'omitnan');
                if rms(sig_dc) < noise_floor
                    filtered_signals{k} = zeros(N_min, 1);
                    fprintf('  [%s]  Sin señal (RMS=%.2f mV < %.2f mV) → zeros\n', ...
                        ch_names{k}, rms(sig_dc), noise_floor);
                    continue
                end

                % ══════════════════════════════════════════════════════════════
                %  ETAPA D — Guard longitud mínima para filtfilt
                % ══════════════════════════════════════════════════════════════
                % filtfilt requiere N ESTRICTAMENTE > min_filt_len (no >=)
                if N_min <= min_filt_len
                    filtered_signals{k} = zeros(N_min, 1);
                    fprintf('  [%s]  Señal demasiado corta (%d <= %d muestras) → zeros\n', ...
                        ch_names{k}, N_min, min_filt_len);
                    continue
                end

                % ══════════════════════════════════════════════════════════════
                %  ETAPA E — High-pass 0.2 Hz
                % ══════════════════════════════════════════════════════════════
                sig = filtfilt(b_hp, a_hp, sig);

                % ══════════════════════════════════════════════════════════════
                %  ETAPA F — Notch 50/100 Hz
                % ══════════════════════════════════════════════════════════════
                for n_f = 1:length(notch_filters)
                    sig = filtfilt(notch_filters{n_f}{1}, notch_filters{n_f}{2}, sig);
                end

                % ══════════════════════════════════════════════════════════════
                %  ETAPA G — Low-pass 10 Hz
                % ══════════════════════════════════════════════════════════════
                sig = filtfilt(b_lp, a_lp, sig);

                % ══════════════════════════════════════════════════════════════
                %  ETAPA H — Validación RMS post-filtrado  (SOLO AVISO)
                % ══════════════════════════════════════════════════════════════
                % v2.3: NUNCA descarta el canal por RMS alto.
                % Motivo: un canal con sacadas grandes legítimas (>30°) puede
                % tener RMS > 60 mV. Zeroearlo eliminaría información clínica.
                % El detector v3.2 usa umbrales adaptativos MAD que funcionan
                % correctamente incluso con señal ruidosa.
                post_rms = rms(sig);
                if post_rms > 60
                    fprintf('  [%s]  AVISO: RMS=%.1f mV (normal 5-30 mV) — canal ruidoso, continuando.\n', ...
                        ch_names{k}, post_rms);
                elseif post_rms > 30
                    fprintf('  [%s]  AVISO: RMS=%.1f mV (ligeramente elevado) — continuando.\n', ...
                        ch_names{k}, post_rms);
                end

                filtered_signals{k} = sig;
                fprintf('  [%s]  OK — RMS=%.2f mV  |  max=%.1f mV  |  min=%.1f mV\n', ...
                    ch_names{k}, post_rms, max(sig), min(sig));

            end   % for k = 1:4

            % ═════════════════════════════════════════════════════════════════
            %  4. GUARDAR Y VALIDAR
            % ═════════════════════════════════════════════════════════════════
            app.SHEOL_f = filtered_signals{1};
            app.SVEOL_f = filtered_signals{2};
            app.SHEOR_f = filtered_signals{3};
            app.SVEOR_f = filtered_signals{4};

            heol_ok = any(app.SHEOL_f ~= 0);
            heor_ok = any(app.SHEOR_f ~= 0);
            veol_ok = any(app.SVEOL_f ~= 0);
            veor_ok = any(app.SVEOR_f ~= 0);

            fprintf('  Canales válidos: SHEOL=%d  SVEOL=%d  SHEOR=%d  SVEOR=%d\n', ...
                heol_ok, veol_ok, heor_ok, veor_ok);

            if ~heol_ok && ~heor_ok && ~veol_ok && ~veor_ok
                fprintf('  ERROR: Todos los canales vacíos.\n');
                uialert(app.UIFigure, ...
                    ['Todos los canales están sin señal o saturados.' newline ...
                    'Comprueba los electrodos y la conexión.'], ...
                    'Sin señal en ningún canal', 'Icon', 'error');
                return
            end

            if ~heol_ok && ~heor_ok
                fprintf('  AVISO: Sin horizontales → solo parpadeos y fijaciones.\n');
                uialert(app.UIFigure, ...
                    ['Los canales horizontales (HEOL y HEOR) no tienen señal.' newline ...
                    'El análisis continuará con parpadeos y fijaciones únicamente.' newline ...
                    'Las sacadas y la main sequence no estarán disponibles.'], ...
                    'Análisis parcial — sin horizontal', 'Icon', 'warning');
            end

            if ~veol_ok && ~veor_ok
                fprintf('  AVISO: Sin verticales → parpadeos no detectables.\n');
            end

            app.SignalsFiltered_OK = true;
            fprintf('  Filtrado completo. Listo para análisis.\n');
            fprintf('==============================================\n\n');
        end

        function TomarMedidasClicked(app)
            ResetSignals(app);
            ChangeWindowTo(app,app.GraficasScreen);
            value = app.ConectionCableCheckBox.Value;
            if value == 1
                DataConnectionCable(app);
                disp(app.Device);
            else
                DataConnection(app);
                disp(app.Device);
            end
        end

        function AccederClicked(app)
            app.usuario = app.UserNameHTML.Data.value;
            app.password = app.PasswordHTML.Data.value;
            disp(app.usuario);
            disp(app.password);
            pathToMLAPP = fileparts(which(class(app)));
            archivoJSON = fullfile(pathToMLAPP, 'usuarios.json');
            texto = fileread(archivoJSON);
            datos = jsondecode(texto);
            if ~isfile(archivoJSON)
                datos.usuarios = struct('username', {}, 'password', {});
            else
                datos = jsondecode(fileread(archivoJSON));
                if ~isfield(datos, 'usuarios')
                    datos.usuarios = struct('username', {}, 'password', {});
                end
            end
            usuarios = datos.usuarios;
            autenticado = false;
            if app.crearcuenta
                yaExiste = any(arrayfun(@(u) strcmp(u.username, app.usuario), usuarios));

                if yaExiste
                    app.SignInStateLBL.Text = "Ese usuario ya existe";
                    return;
                end

                nuevoUsuario.username = app.usuario;
                nuevoUsuario.password = app.password;
                datos.usuarios(end+1) = nuevoUsuario;


                fid = fopen(archivoJSON, 'w');
                fprintf(fid, '%s\n', jsonencode(datos));
                fclose(fid);
                app.UserNameHTML.Data = struct('clear', true);
                app.PasswordHTML.Data = struct('clear', true);
                ChangeWindowTo(app,app.ChooseActionScreen);
                return;
            end
            for i = 1:length(usuarios)
                u = usuarios(i);
                if strcmp(u.username, app.usuario) && strcmp(u.password, app.password)
                    autenticado = true;
                    app.SignInStateLBL.Text = "Sesion iniciada correctamente";
                    break;

                end
            end
            if autenticado
                app.UserNameHTML.Data = struct('clear', true);
                app.PasswordHTML.Data = struct('clear', true);
                ChangeWindowTo(app,app.ChooseActionScreen);
            else
                app.SignInStateLBL.Text = "Usuario o contraseña incorrecta";
            end

        end


        function ConectarGafasClicked(app)
            value = app.ConectionCableCheckBox.Value;
            if value == 1
                TomarMedidasClicked(app);
            else
                try
                    PrepareWifi(app);
                catch ME
                    uialert(app.UIFigure, ME.message, "Error WiFi");
                end
                ChangeWindowTo(app,app.ConectScreen);
                RefreshWifis(app);
            end
        end
        function StartStopClicked(app,tipo)
            if strcmp(tipo, 'Start')
                % Avisar si hay señal previa
                if ~isempty(app.SHEOL) || ~isempty(app.SVEOL) || ~isempty(app.SHEOR) || ~isempty(app.SVEOR)
                    try
                        flush(app.Device);
                    catch
                    end
                    selection = uiconfirm(app.UIFigure, ...
                        'Las señales tomadas seran borradas. ¿Quieres continuar?', ...
                        'Nueva adquisición', ...
                        'Options',       {'Continuar', 'Cancelar'}, ...
                        'DefaultOption', 'Cancelar', ...
                        'CancelOption',  'Cancelar', ...
                        'Icon',          'warning');

                    if strcmp(selection, 'Cancelar')
                        return
                    elseif strcmp(selection, 'Continuar')
                        ResetSignals(app);
                        return
                    end
                end
                % Solo reconectar si el dispositivo se perdió
                if isempty(app.Device)
                    if app.ConectionCableCheckBox.Value == 1
                        DataConnectionCable(app);
                    else
                        DataConnection(app);
                    end
                end
                DataAcquisition(app);
                app.StartStopHTML.Data = app.StopAppearance;
            elseif strcmp(tipo, 'Stop')
                StopAcquisition(app, 'manual');
                app.StartStopHTML.Data = app.StartAppearance;
            end
        end

        function VerAnalisisPreviosClicked(app)
            cargarDebugSignals(app);
        end

        function AnalizarMedidasClicked(app)
            ChangeWindowTo(app,app.AnalisisSignalsScreen);
            PlotSignalsAcquired(app);
            calcularMetricasSacadas(app);
        end

        function ResultadosAnalisisClicked(app)
            if isempty(app.SaccadesMetrics)
                calcularMetricasSacadas(app);
            end

            ChangeWindowTo(app,app.AnalisisScreen);
        end
        function AnalisisEventosClicked(app)
            if isempty(app.SaccadesMetrics)
                calcularMetricasSacadas(app);
            end
            ChangeWindowTo(app,app.EventsAnalisisScreen);
        end
        function ExpandSignalView(app, signalName)
            app.ExpandedPlot.Title.String = signalName;

            if ~app.SignalsFiltered_OK
                ChangeWindowTo(app, app.ExpandedViewScreen);
                return
            end

            fs = app.fs_fixed;

            switch signalName
                case 'R Vertical +'
                    y = app.SVEOR_f;  color = app.ColorSVEOR; N = length(y);
                case 'R Horizontal +'
                    y = app.SHEOR_f;  color = app.ColorSHEOR; N = length(y);
                case 'L Vertical +'
                    y = app.SVEOL_f;  color = app.ColorSVEOL; N = length(y);
                case 'L Horizontal +'
                    y = app.SHEOL_f;  color = app.ColorSHEOL; N = length(y);
                otherwise
                    ChangeWindowTo(app, app.ExpandedViewScreen);
                    return
            end
            t  = (0:N-1) / fs;
            % Guardar señal completa para el scroll
            app.ExpandedSignalTime  = t;

            totalDur = t(end);
            winSize  = min(app.ExpandedViewWindow, totalDur);

            % Dibujar señal completa (solo una vez, luego solo se mueve XLim)
            cla(app.ExpandedPlot);
            plot(app.ExpandedPlot, t, y, 'Color' , color);
            xlabel(app.ExpandedPlot, 'Time (s)');
            ylabel(app.ExpandedPlot, 'mV');
            % Fijar YLim al rango global de TODA la señal
            yMin = min(y);
            yMax = max(y);
            margin = (yMax - yMin) * 0.1;   % 10% de margen arriba y abajo
            if margin == 0
                margin = 1;                  % evitar YLim plano si la señal es constante
            end
            app.ExpandedPlot.YLim     = [yMin - margin, yMax + margin];
            app.ExpandedPlot.YLimMode = 'manual';
            app.ExpandedPlot.XLimMode = 'manual';

            if totalDur > winSize
                app.ExpandedPlot.XLim = [0, winSize];

                app.SignalViewSlider.Limits = [0, totalDur - winSize];
                app.SignalViewSlider.Value  = 0;
                app.SignalViewSlider.MajorTicks = [];
                app.SignalViewSlider.MinorTicks = [];
                app.SignalViewSlider.Visible = 'on';
            else
                % Señal corta, cabe entera
                app.ExpandedPlot.XLim = [0, max(totalDur, 0.1)];
                app.SignalViewSlider.Visible = 'off';
            end

            ChangeWindowTo(app, app.ExpandedViewScreen);
        end

        function BackEventsAnalisisClicked(app)
            ChangeWindowTo(app,app.AnalisisScreen);
        end
        function BackConectClicked(app)
            ChangeWindowTo(app,app.ChooseActionScreen);
        end

        function BackChooseActionClicked(app)
            ChangeWindowTo(app,app.MainScreen);
        end

        function BackAnalisisClicked(app)
            ChangeWindowTo(app,app.AnalisisSignalsScreen);
        end

        function BackGraficasClicked(app)
            selection = uiconfirm(app.UIFigure, ...
                'Se va a desconectar del dispositivo actual ¿Quiere seguir con la operación?', ...
                'Confirmar Desconexión', ...
                'Options',       {'OK', 'Cancel'}, ...
                'DefaultOption', 'OK', ...
                'CancelOption',  'Cancel', ...
                'Icon',          'warning');
            switch selection
                case 'OK'
                    StartStopClicked(app,'Stop');
                    ResetSignals(app);
                    ChangeWindowTo(app,app.ChooseActionScreen);
                case 'Cancel'
                    return
            end
        end
        function BackExpandedViewClicked(app)
            ChangeWindowTo(app,app.AnalisisSignalsScreen);
        end
        function BackAnalisisSignalsClicked(app)
            selection = uiconfirm(app.UIFigure, ...
                'Las señales tomadas no guardadas seran borradas. ¿Quieres continuar?', ...
                'Nueva adquisición', ...
                'Options',       {'Continuar sin guardar', 'Guardar y continuar', 'Cancelar'}, ...
                'DefaultOption', 'Guardar y continuar', ...
                'CancelOption',  'Cancelar', ...
                'Icon',          'question');
            switch selection
                case 'Guardar y continuar'
                    saved = saveSignals(app);
                    if ~saved
                        return  % user cancelled file dialog, abort close
                    end
                case 'Cancelar'
                    return
            end
            ResetSignals(app);
            ChangeWindowTo(app,app.GraficasScreen);
        end

        function PlotSignalsAcquired(app)
            if ~app.SignalsFiltered_OK
                return
            end
            fs = app.fs_fixed;
            N  = length(app.SHEOR_f);
            t  = (0:N-1) / fs;

            pad = t(end) * 0.01;

            plot(app.RHorizontalAxis, t, app.SHEOR_f, 'Color', app.ColorSHEOR);
            xlabel(app.RHorizontalAxis, 'Time (s)'); ylabel(app.RHorizontalAxis, 'mV');
            app.RHorizontalAxis.XLim = [t(1) - pad, t(end) + pad];
            app.RHorizontalAxis.XLimMode = 'manual';

            plot(app.RVerticalAxis, t, app.SVEOR_f, 'Color', app.ColorSVEOR);
            xlabel(app.RVerticalAxis, 'Time (s)'); ylabel(app.RVerticalAxis, 'mV');
            app.RVerticalAxis.XLim = [t(1) - pad, t(end) + pad];
            app.RVerticalAxis.XLimMode = 'manual';

            plot(app.LHorizontalAxis, t, app.SHEOL_f, 'Color', app.ColorSHEOL);
            xlabel(app.LHorizontalAxis, 'Time (s)'); ylabel(app.LHorizontalAxis, 'mV');
            app.LHorizontalAxis.XLim = [t(1) - pad, t(end) + pad];
            app.LHorizontalAxis.XLimMode = 'manual';

            plot(app.LVerticalAxis, t, app.SVEOL_f, 'Color', app.ColorSVEOL);
            xlabel(app.LVerticalAxis, 'Time (s)'); ylabel(app.LVerticalAxis, 'mV');
            app.LVerticalAxis.XLim = [t(1) - pad, t(end) + pad];
            app.LVerticalAxis.XLimMode = 'manual';
        end

        function metrics = detectarEventosOculares(~, heog, veog, fs)
            % DETECTAREVENTOSOCULARES  v3.3  — filosofía SENSIBILIDAD PRIMERO
            %
            % Detecta sacadas, parpadeos y fijaciones en señales EOG sin deadband.
            % Calibrado para AD8232 con C4=4.7µF a ~100x ganancia.
            %
            % CAMBIOS v3.2 vs v3.1 — UMBRAL SENSIBILIDAD
            % ═══════════════════════════════════════════════════════════════
            % Prioridad declarada: detectar eventos aunque alguno sea incorrecto,
            % antes que ser tan conservador que se pierdan eventos reales.
            %
            % Cambios de parámetros:
            %
            %   merge_gap      12ms → 20ms
            %     Con señal ruidosa de AD8232, una misma sacada puede aparecer
            %     fragmentada en 2 segmentos separados por 1-3 muestras de ruido.
            %     Con 12ms (3 muestras a 275 Hz) no se fusionaban. Con 20ms sí.
            %     20ms << isi_min (80ms), por lo que NUNCA fusiona sacadas distintas.
            %
            %   dur_max        150ms → 200ms
            %     Sacadas de >30° pueden superar 150ms. Con electrodo sub-óptimo
            %     la señal es más lenta y sacadas medianas también pueden alargarse.
            %     200ms captura más eventos sin abrir la puerta a artefactos largos
            %     (nystagmus, movimientos de cabeza) que suelen superar 300ms.
            %
            %   amp_min        3.0σ / 12mV → 2.5σ / 8mV
            %     Con el hardware a 100x, una sacada de 2° produce ~16mV en HEOG
            %     diferencial. Con amp_min=12mV ya era detectable, pero bajando
            %     a 8mV se capturan sacadas pequeñas que con electrodo imperfecto
            %     pueden quedar ligeramente atenuadas. El piso de 8mV (10 LSBs a
            %     0.8mV/LSB) sigue siendo 1 orden de magnitud sobre el ruido ADC.
            %
            %   T_high mínimo  40 mV/s → 30 mV/s
            %     El MAD de dos pasadas ya es adaptativo; el mínimo es solo un
            %     suelo de seguridad. Con señal muy tranquila (fijación estable)
            %     el MAD puede dar T_high muy bajo. 30 mV/s sigue siendo seguro:
            %     el ruido de fijación post-LP(10Hz) es típicamente <20 mV/s.
            %
            %   T_low mínimo   20 mV/s → 15 mV/s
            %     Coherente con la bajada de T_high.
            %
            %   T_bamp mínimo  150mV → 120mV
            %     Con electrodo vertical de calidad marginal, la amplitud del
            %     parpadeo en VEOG puede quedar por debajo de 150mV si el contacto
            %     no es perfecto. 120mV sigue siendo muy por encima de sacadas
            %     verticales (30-80mV) y de la fijación post-HP (<50mV).
            %
            %   T_bv mínimo    100 mV/s → 80 mV/s
            %     Parpadeos reales: velocidad pico >> 500 mV/s; 80 mV/s sigue
            %     siendo un suelo conservador pero capta mejor parpadeos lentos
            %     (electrodo parcialmente despegado reduce la velocidad aparente).

            % ══════════════════════════════════════════════════════════════════════
            %  0. PREPARACIÓN
            % ══════════════════════════════════════════════════════════════════════
            heog = double(heog(:));
            veog = double(veog(:));

            % Alinear longitudes (safety net)
            if length(heog) ~= length(veog)
                N_cut = min(length(heog), length(veog));
                heog  = heog(1:N_cut);
                veog  = veog(1:N_cut);
                warning('detectarEventosOculares: longitudes distintas → truncados a %d.', N_cut);
            end

            N = length(heog);
            t = (0:N-1)' / fs;

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 1 — VELOCIDAD (SG 30ms + gradient)
            % ══════════════════════════════════════════════════════════════════════
            sgWin_vel = 2 * floor(fs * 0.030 / 2) + 1;
            sgWin_vel = max(sgWin_vel, 5);

            heog_sg = sgolayfilt(heog, 3, sgWin_vel);
            veog_sg = sgolayfilt(veog, 3, sgWin_vel);

            vel_h    = gradient(heog_sg) * fs;
            vel_v    = gradient(veog_sg) * fs;
            velH_abs = abs(vel_h);
            velV_abs = abs(vel_v);
            velH_refine = velH_abs;   % sin blink-mask, para refinamiento onset/offset

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 2 — UMBRALES SACADA (MAD dos pasadas)
            % ══════════════════════════════════════════════════════════════════════
            th_rough_h   = median(velH_abs) + 6 * median(abs(velH_abs - median(velH_abs)));
            quiet_mask_h = velH_abs < th_rough_h;
            if sum(quiet_mask_h) < 10
                quiet_mask_h = true(size(velH_abs));
            end
            med_h = median(velH_abs(quiet_mask_h));
            mad_h = median(abs(velH_abs(quiet_mask_h) - med_h));

            % v3.2: mínimos reducidos 40→30 y 20→15 mV/s para más sensibilidad
            T_high = max(med_h + 6 * mad_h, 30);   % detección  — mínimo 30 mV/s
            T_low  = max(med_h + 3 * mad_h, 15);   % extensión  — mínimo 15 mV/s

            fprintf('  HEOG umbrales  — T_high: %.1f  T_low: %.1f  mV/s\n', T_high, T_low);

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 3 — DETECCIÓN DE PARPADEOS (VEOG, criterio AND)
            % ══════════════════════════════════════════════════════════════════════

            % ── 3a. Umbral velocidad vertical (MAD dos pasadas) ──────────────────
            th_rough_v   = median(velV_abs) + 6 * median(abs(velV_abs - median(velV_abs)));
            quiet_mask_v = velV_abs < th_rough_v;
            if sum(quiet_mask_v) < 10
                quiet_mask_v = true(size(velV_abs));
            end
            med_va = median(velV_abs(quiet_mask_v));
            mad_va = median(abs(velV_abs(quiet_mask_v) - med_va));

            % v3.2: mínimo 80 mV/s (era 100). Parpadeos reales >> 500 mV/s.
            T_bv = max(med_va + 5 * mad_va, 80);

            % ── 3b. Umbral amplitud parpadeo (MAD dos pasadas sobre VEOG completo)
            % v3.2: mínimo 120 mV (era 150). Con electrodo vertical marginal,
            % amplitudes de parpadeo pueden quedar por debajo de 150 mV.
            veog_abs = abs(veog);
            med_b0   = median(veog_abs);
            mad_b0   = median(abs(veog_abs - med_b0));
            quiet_amp_v = veog_abs < (med_b0 + 6 * mad_b0 * 1.4826);
            if sum(quiet_amp_v) < 10
                quiet_amp_v = true(N, 1);
            end
            q_med_v     = median(veog_abs(quiet_amp_v));
            amp_noise_v = median(abs(veog_abs(quiet_amp_v) - q_med_v)) * 1.4826;

            T_bamp = max(amp_noise_v * 6.0, 120);   % v3.2: mínimo 120 mV (era 150)

            fprintf('  VEOG umbrales  — T_bv: %.1f mV/s  T_bamp: %.1f mV\n', T_bv, T_bamp);

            % ── 3c. Candidatos a parpadeo ─────────────────────────────────────────
            rawBlink = (velV_abs > T_bv) & (veog_abs > T_bamp);

            d_b  = diff([0; rawBlink; 0]);
            bIni = find(d_b ==  1);
            bFin = find(d_b == -1) - 1;

            bDur_samp = bFin - bIni + 1;

            blink_min_samp = round(0.080 * fs);   % 80 ms: parpadeos espontáneos mínimo
            blink_max_samp = round(0.600 * fs);   % 600 ms: parpadeo muy prolongado

            keepB = (bDur_samp >= blink_min_samp) & (bDur_samp <= blink_max_samp);
            bIni  = bIni(keepB);
            bFin  = bFin(keepB);

            blinkHold = round(0.050 * fs);
            blinkMask = false(N, 1);
            for i = 1:length(bIni)
                i1 = max(1, bIni(i) - blinkHold);
                i2 = min(N, bFin(i) + blinkHold);
                blinkMask(i1:i2) = true;
            end

            fprintf('  Parpadeos detectados: %d\n', length(bIni));

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 4 — CANDIDATOS A SACADA (HEOG, sin zonas de parpadeo)
            % ══════════════════════════════════════════════════════════════════════
            velH_clean = velH_abs;
            velH_clean(blinkMask) = 0;

            isSacc_high = velH_clean > T_high;
            d_s  = diff([0; isSacc_high; 0]);
            cIni = find(d_s ==  1);
            cFin = find(d_s == -1) - 1;

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 5 — REFINAMIENTO ONSET / OFFSET (85ms, con velH_refine)
            % ══════════════════════════════════════════════════════════════════════
            ext_samp = round(0.085 * fs);
            nCand    = length(cIni);
            sIni     = zeros(nCand, 1);
            sFin     = zeros(nCand, 1);

            for k = 1:nCand
                i_start = max(1, cIni(k) - ext_samp);
                pre_seg = velH_refine(i_start:cIni(k));
                below   = find(pre_seg < T_low, 1, 'last');
                if isempty(below)
                    sIni(k) = i_start;
                else
                    sIni(k) = i_start + below - 1;
                end

                i_end    = min(N, cFin(k) + ext_samp);
                post_seg = velH_refine(cFin(k):i_end);
                below    = find(post_seg < T_low, 1, 'first');
                if isempty(below)
                    sFin(k) = i_end;
                else
                    sFin(k) = cFin(k) + below - 2;
                end
                sFin(k) = max(sFin(k), cFin(k));
            end

            validOO = sIni < sFin;
            sIni    = sIni(validOO);
            sFin    = sFin(validOO);

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 6 — FUSIÓN DE CANDIDATOS PRÓXIMOS
            % ══════════════════════════════════════════════════════════════════════
            % v3.2: 20ms (era 12ms). Con señal ruidosa de AD8232, la misma sacada
            % puede aparecer fragmentada. 20ms << isi_min (80ms), nunca fusiona
            % sacadas neurológicamente distintas.
            merge_gap = round(0.020 * fs);

            if ~isempty(sIni)
                m_ini    = sIni(1);
                m_fin    = sFin(1);
                mIniList = zeros(length(sIni), 1);
                mFinList = zeros(length(sIni), 1);
                nMerged  = 0;

                for k = 2:length(sIni)
                    if sIni(k) - m_fin <= merge_gap
                        m_fin = max(m_fin, sFin(k));
                    else
                        nMerged = nMerged + 1;
                        mIniList(nMerged) = m_ini;
                        mFinList(nMerged) = m_fin;
                        m_ini = sIni(k);
                        m_fin = sFin(k);
                    end
                end
                nMerged = nMerged + 1;
                mIniList(nMerged) = m_ini;
                mFinList(nMerged) = m_fin;

                sIni = mIniList(1:nMerged);
                sFin = mFinList(1:nMerged);
            end

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 7 — VALIDACIÓN FISIOLÓGICA
            % ══════════════════════════════════════════════════════════════════════
            dur_min = round(0.010 * fs);   % 10 ms mínimo
            % v3.2: 200ms (era 150ms) — captura sacadas grandes y señal algo lenta
            dur_max = round(0.200 * fs);
            isi_min = round(0.080 * fs);   % 80 ms período refractario

            % amp_min: MAD dos pasadas sobre HEOG centrado
            %
            % v3.3: 1.0σ / 5mV (era 2.5σ / 8mV en v3.2)
            %
            % POR QUÉ SE BAJA TANTO:
            % Los umbrales anteriores (2.5σ) calculaban amp_min en función del ruido
            % de fijación de HEOG. Con electrodos sub-óptimos (AD8232, C4=4.7µF),
            % ese ruido puede ser 20-40 mV → amp_min = 50-100 mV → solo se detectan
            % sacadas muy grandes. Las sacadas de 5-15° con electrodo imperfecto
            % pueden tener amplitude diferencial de 20-60 mV en HEOG y quedaban
            % todas rechazadas.
            %
            % Con 1.0σ / 5mV el filtro de amplitud actúa solo como guardia contra
            % candidatos con amplitud literalmente nula (artefactos de borde). El
            % trabajo de distinguir sacadas reales de ruido lo hace el umbral de
            % velocidad T_high (adaptativo por MAD), que es más robusto para
            % señal continua sin deadband.
            heog_c    = heog - median(heog);
            heog_abs  = abs(heog_c);
            med_hc0   = median(heog_abs);
            mad_hc0   = median(abs(heog_abs - med_hc0));
            quiet_hc  = heog_abs < (med_hc0 + 6 * mad_hc0 * 1.4826);
            if sum(quiet_hc) < 10
                quiet_hc = true(N, 1);
            end
            heog_noise = median(abs(heog_abs(quiet_hc) - median(heog_abs(quiet_hc)))) * 1.4826;
            amp_min    = max(heog_noise * 1.0, 5);   % v3.3: 1.0σ, mínimo 5 mV

            % Ventana 30ms para estimar el nivel basal antes/después de cada sacada.
            % v3.3: 30ms (era 15ms). Ventana más larga → estimación más robusta del
            % nivel de fijación, menos sensible a picos de ruido aislados en el borde.
            win_s = max(round(0.030 * fs), 5);

            fprintf('  HEOG amp_min   — %.1f mV  (ruido_fijación=%.1f mV)\n', amp_min, heog_noise);
            fprintf('  HEOG T_high    — %.1f mV/s  T_low: %.1f mV/s\n', T_high, T_low);

            % ── Filtro duración ───────────────────────────────────────────────────
            n_tras_velocidad = length(sIni);
            durSamp = sFin - sIni + 1;
            keep    = (durSamp >= dur_min) & (durSamp <= dur_max);
            sIni    = sIni(keep);
            sFin    = sFin(keep);
            n_tras_duracion = length(sIni);

            % ── Filtro amplitud ───────────────────────────────────────────────────
            keepAmp = false(size(sIni));
            for k = 1:length(sIni)
                i1 = sIni(k);
                i2 = sFin(k);
                pre_idx  = max(1, i1 - win_s) : i1;
                post_idx = i2 : min(N, i2 + win_s);
                if length(pre_idx) < 2 || length(post_idx) < 2
                    continue
                end
                amp_on  = mean(heog(pre_idx));
                amp_off = mean(heog(post_idx));
                if abs(amp_off - amp_on) >= amp_min
                    keepAmp(k) = true;
                end
            end
            sIni = sIni(keepAmp);
            sFin = sFin(keepAmp);
            n_tras_amplitud = length(sIni);

            % ── Filtro ISI (lastKept) ─────────────────────────────────────────────
            if length(sIni) > 1
                keepISI  = true(size(sIni));
                lastKept = 1;

                for k = 2:length(sIni)
                    if sIni(k) - sFin(lastKept) < isi_min
                        vPrev = max(velH_clean(sIni(lastKept):sFin(lastKept)));
                        vCurr = max(velH_clean(sIni(k):sFin(k)));
                        if vPrev >= vCurr
                            keepISI(k) = false;
                        else
                            keepISI(lastKept) = false;
                            lastKept = k;
                        end
                    else
                        lastKept = k;
                    end
                end

                sIni = sIni(keepISI);
                sFin = sFin(keepISI);
            end
            n_tras_isi = length(sIni);

            % ── DIAGNÓSTICO: embudo de filtros ────────────────────────────────────
            % Esta línea aparece en la consola de MATLAB y permite entender por qué
            % se pierden candidatos. Si n_tras_velocidad es alto pero n_tras_amplitud
            % es bajo, el problema es amp_min. Si n_tras_velocidad ya es bajo,
            % el problema es T_high o HEOG diferencial casi nulo.
            fprintf('  Embudo filtros : %d vel → %d dur → %d amp → %d ISI = %d sacadas\n', ...
                n_tras_velocidad, n_tras_duracion, n_tras_amplitud, n_tras_isi, n_tras_isi);

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 8 — CARACTERÍSTICAS POR SACADA
            % ══════════════════════════════════════════════════════════════════════
            nSac      = length(sIni);
            onsetIdx  = sIni;
            offsetIdx = sFin;
            peakIdx   = zeros(nSac, 1);

            onsetTime  = t(sIni);
            offsetTime = t(min(sFin, N));
            durMs      = (sFin - sIni + 1) / fs * 1000;
            amplitude  = zeros(nSac, 1);
            peakVel    = zeros(nSac, 1);
            direction  = strings(nSac, 1);

            for k = 1:nSac
                seg_v      = velH_clean(sIni(k):sFin(k));
                [pv, pvi]  = max(seg_v);
                peakIdx(k) = sIni(k) + pvi - 1;
                peakVel(k) = pv;

                i1 = sIni(k);
                i2 = sFin(k);
                amp_on       = mean(heog(max(1, i1 - win_s) : i1));
                amp_off      = mean(heog(i2 : min(N, i2 + win_s)));
                amplitude(k) = amp_off - amp_on;

                if amplitude(k) > 0
                    direction(k) = "derecha";
                else
                    direction(k) = "izquierda";
                end
            end

            if nSac > 0
                peakTime = t(peakIdx);
            else
                peakTime = zeros(0, 1);
            end

            fprintf('  Sacadas OK: %d  (D:%d I:%d) | amp_min=%.1f mV | T_high=%.1f | heog_noise=%.1f mV\n', ...
                nSac, sum(direction=="derecha"), sum(direction=="izquierda"), amp_min, T_high, heog_noise);

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 9 — CARACTERÍSTICAS PARPADEOS
            % ══════════════════════════════════════════════════════════════════════
            nBlink      = length(bIni);
            blinkOnset  = zeros(nBlink, 1);
            blinkOffset = zeros(nBlink, 1);
            blinkDurMs  = zeros(nBlink, 1);
            blinkAmp    = zeros(nBlink, 1);

            for k = 1:nBlink
                blinkOnset(k)  = t(bIni(k));
                bFinK          = min(bFin(k), N);
                blinkOffset(k) = t(bFinK);
                blinkDurMs(k)  = (bFinK - bIni(k) + 1) / fs * 1000;
                blinkAmp(k)    = max(abs(veog(bIni(k):bFinK)));
            end

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 10 — FIJACIONES
            % ══════════════════════════════════════════════════════════════════════
            eventMask = blinkMask;
            for k = 1:nSac
                eventMask(sIni(k):sFin(k)) = true;
            end

            d_fix  = diff([false; ~eventMask; false]);
            fixIni = find(d_fix ==  1);
            fixFin = find(d_fix == -1) - 1;

            fix_min_samp = round(0.100 * fs);
            fixDur_samp  = fixFin - fixIni + 1;
            keepFix      = fixDur_samp >= fix_min_samp;
            fixIni = fixIni(keepFix);
            fixFin = fixFin(keepFix);

            nFix     = length(fixIni);
            fixDurMs = (fixFin - fixIni + 1) / fs * 1000;
            fixOnset  = t(fixIni);
            fixOffset = t(min(fixFin, N));

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 11 — MAIN SEQUENCE (n≥7, rango≥40mV)
            % ══════════════════════════════════════════════════════════════════════
            mainSeq_r     = NaN;
            mainSeq_p     = NaN;
            mainSeq_slope = NaN;
            range_amp_mV  = 0;
            ms_reason     = sprintf('n=%d < 7', nSac);

            MIN_SAC_CORR = 7;
            MIN_RANGE_MV = 40;

            if nSac >= MIN_SAC_CORR
                amp_abs      = abs(amplitude);
                range_amp_mV = max(amp_abs) - min(amp_abs);

                if range_amp_mV >= MIN_RANGE_MV
                    [mainSeq_r, mainSeq_p] = corr(amp_abs, peakVel, 'type', 'Spearman');

                    validMS = amp_abs > 0 & peakVel > 0;
                    if sum(validMS) >= MIN_SAC_CORR
                        p_ms = polyfit(log(amp_abs(validMS)), log(peakVel(validMS)), 1);
                        mainSeq_slope = p_ms(1);
                    end
                    ms_reason = '';
                    fprintf('  Main sequence  — r=%.3f  p=%.4f  rango=%.0f mV\n', ...
                        mainSeq_r, mainSeq_p, range_amp_mV);
                else
                    ms_reason = sprintf('rango %.0f mV < %.0f mV', range_amp_mV, MIN_RANGE_MV);
                    fprintf('  Main sequence  — %s\n', ms_reason);
                end
            end

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 12 — MÉTRICAS GLOBALES
            % ══════════════════════════════════════════════════════════════════════
            totalTime = N / fs;

            idxRight = direction == "derecha";
            idxLeft  = direction == "izquierda";
            nRight   = sum(idxRight);
            nLeft    = sum(idxLeft);

            freq_total = nSac / totalTime;
            freq_min   = freq_total * 60;

            if nSac > 0
                mean_vel   = mean(peakVel,           'omitnan');
                mean_vel_r = mean(peakVel(idxRight), 'omitnan');
                mean_vel_l = mean(peakVel(idxLeft),  'omitnan');
                mean_dur   = mean(durMs,             'omitnan');
                mean_amp   = mean(abs(amplitude),    'omitnan');
            else
                mean_vel = 0; mean_vel_r = 0; mean_vel_l = 0;
                mean_dur = 0; mean_amp   = 0;
            end
            if isnan(mean_vel_r), mean_vel_r = 0; end
            if isnan(mean_vel_l), mean_vel_l = 0; end

            mean_fix = mean(fixDurMs, 'omitnan');
            if isnan(mean_fix), mean_fix = 0; end

            asymmetry  = (nRight - nLeft) / max(nRight + nLeft, 1);
            blink_rate = nBlink / totalTime * 60;

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 13 — TABLAS DE EVENTOS
            % ══════════════════════════════════════════════════════════════════════
            if nSac > 0
                eventsTable = table( ...
                    onsetIdx,  offsetIdx,  peakIdx,   ...
                    onsetTime, offsetTime, peakTime,  ...
                    durMs,     amplitude,  peakVel,   direction, ...
                    'VariableNames', { ...
                    'onsetIdx','offsetIdx','peakIdx', ...
                    'onsetTime','offsetTime','peakTime', ...
                    'duration_ms','amplitude_mV','peakVel_mVs','direction'});
            else
                eventsTable = table();
            end

            if nBlink > 0
                blinksTable = table( ...
                    bIni(:), bFin(:), blinkOnset, blinkOffset, blinkDurMs, blinkAmp, ...
                    'VariableNames', { ...
                    'onsetIdx','offsetIdx','onsetTime','offsetTime', ...
                    'duration_ms','amplitude_mV'});
            else
                blinksTable = table();
            end

            if nFix > 0
                fixTable = table( ...
                    fixIni, fixFin, fixOnset, fixOffset, fixDurMs, ...
                    'VariableNames', { ...
                    'onsetIdx','offsetIdx','onsetTime','offsetTime','duration_ms'});
            else
                fixTable = table();
            end

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 14 — EMPAQUETAR
            % ══════════════════════════════════════════════════════════════════════
            metrics = struct();

            metrics.eventsTable   = eventsTable;
            metrics.blinksTable   = blinksTable;
            metrics.fixTable      = fixTable;

            metrics.duration      = durMs;
            metrics.amplitude     = amplitude;
            metrics.peakVel       = peakVel;
            metrics.direction     = direction;

            metrics.num_total     = nSac;
            metrics.num_right     = nRight;
            metrics.num_left      = nLeft;
            metrics.num_blinks    = nBlink;
            metrics.num_fix       = nFix;

            metrics.frequency_total = freq_total;
            metrics.frequency_min   = freq_min;
            metrics.blink_rate      = blink_rate;

            metrics.mean_velocity   = mean_vel;
            metrics.mean_velocity_r = mean_vel_r;
            metrics.mean_velocity_l = mean_vel_l;
            metrics.mean_duration   = mean_dur;
            metrics.mean_amplitude  = mean_amp;
            metrics.mean_fixation   = mean_fix;

            metrics.asymmetry_num   = asymmetry;
            metrics.mainSeq_r       = mainSeq_r;
            metrics.mainSeq_p       = mainSeq_p;
            metrics.mainSeq_slope   = mainSeq_slope;
            metrics.range_amp_mV    = range_amp_mV;
            metrics.ms_reason       = ms_reason;

            metrics.threshold_vel       = T_high;
            metrics.threshold_vel_low   = T_low;
            metrics.threshold_blink_vel = T_bv;
            metrics.threshold_blink_amp = T_bamp;
            metrics.amp_min_used        = amp_min;
            metrics.heog_noise_floor    = heog_noise;
        end

        function calcularMetricasSacadas(app)
            % CALCULARMETRICASSACADAS  v2.0
            % Coordina el cálculo de HEOG/VEOG y la detección de eventos oculares.
            %
            % Responsabilidades:
            %   1. Validar que las señales filtradas y fs_fixed están disponibles
            %   2. Alinear longitudes de canales filtrados (safety net)
            %   3. Calcular HEOG y VEOG de forma ADAPTATIVA según los canales válidos
            %   4. Validar que HEOG resultante tiene señal suficiente
            %   5. Llamar a detectarEventosOculares v3.0
            %   6. Anotar metadatos de calidad de canal en el struct de métricas
            %   7. Actualizar UI (resultados + tabla de eventos)
            %
            % MEJORAS v2.0 vs v1.x:
            %
            %  ── HEOG/VEOG ADAPTATIVOS (bug principal corregido) ──────────────
            %  En v1.x:
            %      HEOG = (SHEOR_f - SHEOL_f) / 2
            %      VEOG = (SVEOR_f + SVEOL_f) / 2
            %  Si un canal estaba zeroed (saturado, electrodo muerto), la
            %  fórmula producía exactamente la mitad de la amplitud real:
            %      HEOG = (señal_real - 0) / 2  → amplitud 50% → sacadas subestimadas
            %  Ahora se detecta la disponibilidad de cada canal y se adapta:
            %      Ambos OK   → diferencial /2 (máximo SNR, convención estándar)
            %      Solo SHEOR → SHEOR directamente (amplitud real, sin dividir)
            %      Solo SHEOL → -SHEOL directamente (con inversión de signo)
            %      Ninguno    → error (ya bloqueado por filterSignalsPost)
            %
            %  ── VALIDACIONES ADICIONALES ─────────────────────────────────────
            %  · Alineación de longitudes entre canales (safety net)
            %  · Duración mínima 1 s (análisis sin sentido en registros < 1 s)
            %  · RMS de HEOG mínimo 1 mV (detecta HEOG trivialmente plano)
            %
            %  ── METADATOS DE CALIDAD EN MÉTRICAS ─────────────────────────────
            %  El struct SaccadesMetrics ahora incluye:
            %      .channel_heog  — descripción del modo de cálculo HEOG
            %      .channel_veog  — descripción del modo de cálculo VEOG
            %      .heol_ok / .heor_ok / .veol_ok / .veor_ok  — flags por canal
            %      .duration_s    — duración real del registro analizado
            %  Estos campos son usados por actualizarResultadosSacadas para
            %  mostrar advertencias de canal en la UI.
            %
            %  ── TRY/CATCH SOBRE EL DETECTOR ──────────────────────────────────
            %  Envuelve detectarEventosOculares en try/catch para evitar que
            %  un error inesperado en el detector cierre la app sin mensaje claro.

            % ═════════════════════════════════════════════════════════════════
            %  1. VALIDACIONES INICIALES
            % ═════════════════════════════════════════════════════════════════
            if ~app.SignalsFiltered_OK
                uialert(app.UIFigure, ...
                    ['Las señales aún no han sido filtradas.' newline ...
                    'Adquiere una señal y pulsa "Analizar Medidas".'], ...
                    'Sin señales filtradas', 'Icon', 'warning');
                return
            end

            if isempty(app.fs_fixed) || app.fs_fixed <= 0
                uialert(app.UIFigure, ...
                    ['fs_fixed no está disponible.' newline ...
                    'Esto no debería ocurrir si SignalsFiltered_OK es true.' newline ...
                    'Carga de nuevo las señales.'], ...
                    'Error interno: fs', 'Icon', 'error');
                return
            end

            fs = app.fs_fixed;

            % ═════════════════════════════════════════════════════════════════
            %  2. CALIDAD DE CANALES Y ALINEACIÓN DE LONGITUDES
            % ═════════════════════════════════════════════════════════════════
            heol_ok = any(app.SHEOL_f ~= 0);
            heor_ok = any(app.SHEOR_f ~= 0);
            veol_ok = any(app.SVEOL_f ~= 0);
            veor_ok = any(app.SVEOR_f ~= 0);

            % filterSignalsPost garantiza longitudes iguales (N_min),
            % pero alineamos por robustez ante cargas externas de fichero.
            all_lens = [length(app.SHEOL_f), length(app.SVEOL_f), ...
                length(app.SHEOR_f), length(app.SVEOR_f)];
            lens_valid = all_lens(all_lens > 0);

            if isempty(lens_valid)
                uialert(app.UIFigure, ...
                    'Todas las señales filtradas tienen longitud cero.', ...
                    'Señales vacías', 'Icon', 'error');
                return
            end

            N = min(lens_valid);

            % Duración mínima de 1 s para que el análisis tenga sentido
            MIN_DUR_S = 1.0;
            if N < round(MIN_DUR_S * fs)
                uialert(app.UIFigure, ...
                    sprintf(['Registro demasiado corto (%.2f s).' newline ...
                    'Se necesita al menos %.0f s para un análisis válido.'], ...
                    N/fs, MIN_DUR_S), ...
                    'Señal insuficiente', 'Icon', 'warning');
                return
            end

            % Recortar todos los canales a N muestras
            SHEOL_ff = app.SHEOL_f(1:N);
            SVEOL_ff = app.SVEOL_f(1:N);
            SHEOR_ff = app.SHEOR_f(1:N);
            SVEOR_ff = app.SVEOR_f(1:N);

            % ═════════════════════════════════════════════════════════════════
            %  3. CÁLCULO ADAPTATIVO DE HEOG
            % ═════════════════════════════════════════════════════════════════
            % Convención de signo clínica estándar:
            %   SHEOR (+): electrodo canto externo ojo DERECHO
            %              Mirada derecha → potencial positivo en AD8232
            %   SHEOL (+): electrodo canto externo ojo IZQUIERDO
            %              Mirada derecha → potencial negativo en AD8232
            %   HEOG = (SHEOR - SHEOL) / 2  →  mirada derecha = positivo
            %
            % IMPORTANTE: el signo depende de qué entrada del AD8232 (IN+/IN-)
            % se conecta a cada electrodo. Verificar empiricamente con el hardware.
            %
            % Modo monocanal: cuando solo uno de los dos canales es válido,
            % se usa directamente SIN dividir por 2 para conservar la amplitud
            % real de la señal en lugar de tenerla al 50%.

            if heol_ok && heor_ok
                heog_mode = 'diferencial (SHEOR-SHEOL)/2  [ambos canales OK]';
                HEOG      = (SHEOR_ff - SHEOL_ff) / 2;

            elseif heor_ok
                heog_mode = 'monocanal SHEOR  [SHEOL zeroed — amplitud no dividida]';
                HEOG      = SHEOR_ff;
                fprintf('  Aviso HEOG: solo SHEOR disponible. Amplitudes en escala 1x (no /2).\n');

            elseif heol_ok
                heog_mode = 'monocanal -SHEOL  [SHEOR zeroed — signo invertido, amplitud no dividida]';
                HEOG      = -SHEOL_ff;
                fprintf('  Aviso HEOG: solo SHEOL disponible. Signo invertido, amplitudes en escala 1x.\n');

            else
                heog_mode = 'no disponible  [ambos canales horizontales zeroed]';
                HEOG      = zeros(N, 1);
                fprintf('  Aviso HEOG: sin canales horizontales → 0 sacadas, parpadeos OK.\n');
                % No return — detectarEventosOculares con HEOG=ceros detecta
                % 0 sacadas pero funciona correctamente para parpadeos y fijaciones.
            end

            % Validación: HEOG no debe ser trivialmente plano
            heog_rms = rms(HEOG);
            % Solo bloquear si teníamos canales horizontales pero la señal es plana.
            % Si no hay canales (HEOG=zeros intencionado), el check no aplica.
            if (heol_ok || heor_ok) && heog_rms < 1.0
                uialert(app.UIFigure, ...
                    sprintf(['HEOG calculado tiene RMS = %.3f mV (< 1 mV).' newline ...
                    'La señal diferencial es prácticamente cero.' newline ...
                    'Posibles causas: electrodos sin contacto, ganancia incorrecta,' newline ...
                    'o movimientos oculares mínimos durante el registro.'], heog_rms), ...
                    'HEOG insuficiente', 'Icon', 'warning');
                return
            end

            % ═════════════════════════════════════════════════════════════════
            %  4. CÁLCULO ADAPTATIVO DE VEOG
            % ═════════════════════════════════════════════════════════════════
            % VEOG = suma / 2 de ambos canales verticales cuando están disponibles.
            % Los dos canales verticales deben tener la misma polaridad ante
            % movimientos verticales (el ojo sube → ambos positivos o ambos
            % negativos según la conexión IN+/IN-). Verificar con el hardware.
            %
            % Sin canales verticales: VEOG = ceros. El detector v3.0 lo maneja
            % correctamente (sin parpadeos detectados, sacadas horizontales OK).

            if veol_ok && veor_ok
                veog_mode = 'media (SVEOR+SVEOL)/2  [ambos canales OK]';
                VEOG      = (SVEOR_ff + SVEOL_ff) / 2;

            elseif veor_ok
                veog_mode = 'monocanal SVEOR  [SVEOL zeroed]';
                VEOG      = SVEOR_ff;
                fprintf('  Aviso VEOG: solo SVEOR disponible.\n');

            elseif veol_ok
                veog_mode = 'monocanal SVEOL  [SVEOR zeroed]';
                VEOG      = SVEOL_ff;
                fprintf('  Aviso VEOG: solo SVEOL disponible.\n');

            else
                veog_mode = 'no disponible  [ambos canales verticales zeroed]';
                VEOG      = zeros(N, 1);
                fprintf('  Aviso VEOG: sin canales verticales — parpadeos no detectables.\n');
            end

            % ═════════════════════════════════════════════════════════════════
            %  5. LOG DE CONFIGURACIÓN
            % ═════════════════════════════════════════════════════════════════
            fprintf('\n=== CALCULAR MÉTRICAS SACADAS v2.0 ===\n');
            fprintf('  Duración : %.2f s  |  fs: %d Hz  |  %d muestras\n', N/fs, fs, N);
            fprintf('  HEOG     : %s\n', heog_mode);
            fprintf('  VEOG     : %s\n', veog_mode);
            fprintf('  HEOG RMS : %.2f mV\n', heog_rms);
            fprintf('  Canales  : HEOL=%d HEOR=%d VEOL=%d VEOR=%d\n', ...
                heol_ok, heor_ok, veol_ok, veor_ok);

            % ═════════════════════════════════════════════════════════════════
            %  6. GUARDAR SEÑALES GLOBALES
            % ═════════════════════════════════════════════════════════════════
            app.HEOG_global = HEOG;
            app.VEOG_global = VEOG;

            % ═════════════════════════════════════════════════════════════════
            %  7. DETECCIÓN DE EVENTOS (con progress y try/catch)
            % ═════════════════════════════════════════════════════════════════
            dlg = uiprogressdlg(app.UIFigure, ...
                'Title',       'Analizando señales EOG...', ...
                'Message',     'Detectando sacadas, parpadeos y fijaciones...', ...
                'Indeterminate','on');
            drawnow;

            try
                metrics = detectarEventosOculares(app, HEOG, VEOG, fs);
            catch ME
                close(dlg);
                uialert(app.UIFigure, ...
                    sprintf(['Error inesperado en el detector de eventos:' newline '%s'], ...
                    ME.message), ...
                    'Error de análisis', 'Icon', 'error');
                fprintf('  ERROR en detectarEventosOculares: %s\n', ME.message);
                fprintf('%s\n', ME.getReport('basic'));
                return
            end

            close(dlg);

            % ═════════════════════════════════════════════════════════════════
            %  8. ANOTAR METADATOS DE CALIDAD EN EL STRUCT
            % ═════════════════════════════════════════════════════════════════
            % Estos campos extras son consumidos por actualizarResultadosSacadas
            % para mostrar advertencias de canal en la UI (p.ej. avisar que
            % las amplitudes están en escala 1x porque falta un canal horizontal).

            metrics.channel_heog = heog_mode;
            metrics.channel_veog = veog_mode;
            metrics.heol_ok      = heol_ok;
            metrics.heor_ok      = heor_ok;
            metrics.veol_ok      = veol_ok;
            metrics.veor_ok      = veor_ok;
            metrics.heog_rms     = heog_rms;
            metrics.duration_s   = N / fs;

            % Advertencia si se operó en modo monocanal horizontal
            metrics.heog_monochannel = ~(heol_ok && heor_ok);
            metrics.heog_unavailable = ~heol_ok && ~heor_ok;

            app.SaccadesMetrics = metrics;

            % ═════════════════════════════════════════════════════════════════
            %  9. LOG DE RESULTADOS RÁPIDO
            % ═════════════════════════════════════════════════════════════════
            fprintf('  Sacadas   : %d  (D:%d  I:%d)\n', ...
                metrics.num_total, metrics.num_right, metrics.num_left);
            fprintf('  Parpadeos : %d  (%.1f/min)\n', ...
                metrics.num_blinks, metrics.blink_rate);
            fprintf('  Fijaciones: %d  (media %.0f ms)\n', ...
                metrics.num_fix, metrics.mean_fixation);
            if ~isnan(metrics.mainSeq_r)
                fprintf('  Main seq  : r=%.3f  p=%.4f\n', ...
                    metrics.mainSeq_r, metrics.mainSeq_p);
            elseif isfield(metrics, 'ms_reason') && ~isempty(metrics.ms_reason)
                fprintf('  Main seq  : NaN (%s)\n', metrics.ms_reason);
            end
            fprintf('=======================================\n\n');

            % ═════════════════════════════════════════════════════════════════
            %  10. ACTUALIZAR UI
            % ═════════════════════════════════════════════════════════════════
            actualizarResultadosSacadas(app);
            actualizarTablaEventosSacadas(app);
        end


        function actualizarResultadosSacadas(app)

            if isempty(app.SaccadesMetrics)
                return
            end

            m = app.SaccadesMetrics;

            % ── Paleta de colores ────────────────────────────────────────────────
            C_GREEN  = [0.16  0.73  0.51];
            C_ORANGE = [0.95  0.45  0.09];
            C_RED    = [0.85  0.10  0.15];
            C_PURPLE = [0.36  0.07  0.80];
            C_CYAN   = [0.00  0.71  0.85];
            C_MUTED  = [0.55  0.55  0.60];
            BG_LAVANDA = [0.902 0.902 0.9804];

            % ════════════════════════════════════════════════════════════════════
            %  1. HEADER — Fecha · Duración · Frecuencia
            % ════════════════════════════════════════════════════════════════════
            app.TimeLBL.Text      = datestr(now, 'dd/mm/yyyy  HH:MM');
            app.DurationLBL.Text  = sprintf('%.0f s', length(app.SHEOR_f) / app.fs_fixed);
            app.FrequencyLBL.Text = sprintf('%.0f Hz', app.fs_fixed);

            % ════════════════════════════════════════════════════════════════════
            %  2. GAUGE — Tasa de parpadeo
            % ════════════════════════════════════════════════════════════════════
            app.Gauge.Value = min(max(m.blink_rate, 0), 40);

            % Label8 → valor numérico grande
            app.RateParpadeosLBL.Text     = sprintf('%.1f', m.blink_rate);
            app.RateParpadeosLBL.FontSize = 20;
            app.RateParpadeosLBL.FontWeight = 'bold';

            % Label9 → total de parpadeos
            app.TotalParpadeosLBL.Text = sprintf('%d total', m.num_blinks);

            if m.blink_rate < 8
                app.RateParpadeosLBL.FontColor = C_ORANGE;          % fatiga
            elseif m.blink_rate > 25
                app.RateParpadeosLBL.FontColor = C_RED;             % irritación
            else
                app.RateParpadeosLBL.FontColor = C_GREEN;           % normal
            end

            % ════════════════════════════════════════════════════════════════════
            %  3. SCATTER MAIN SEQUENCE — UIAxes
            % ════════════════════════════════════════════════════════════════════
            cla(app.CorrAxes);
            hold(app.CorrAxes, 'on');

            if m.num_total > 0 && ~isempty(m.eventsTable)
                T    = m.eventsTable;
                idxR = strcmp(string(T.direction), 'derecha');
                idxL = strcmp(string(T.direction), 'izquierda');

                % Puntos por dirección
                if any(idxR)
                    scatter(app.CorrAxes, abs(T.amplitude_mV(idxR)), T.peakVel_mVs(idxR), ...
                        22, C_CYAN, 'filled', 'MarkerFaceAlpha', 0.85);
                end
                if any(idxL)
                    scatter(app.CorrAxes, abs(T.amplitude_mV(idxL)), T.peakVel_mVs(idxL), ...
                        22, C_PURPLE, 'filled', 'MarkerFaceAlpha', 0.85);
                end

                % Línea de tendencia
                amp_all = abs(T.amplitude_mV);
                vel_all = T.peakVel_mVs;
                validMS = amp_all > 0 & vel_all > 0;
                if sum(validMS) >= 3
                    p  = polyfit(amp_all(validMS), vel_all(validMS), 1);
                    xf = linspace(min(amp_all(validMS)), max(amp_all(validMS)), 60);
                    plot(app.CorrAxes, xf, polyval(p, xf), '--', ...
                        'Color', [0.7 0.7 0.75], 'LineWidth', 1.2);
                end
            end

            hold(app.CorrAxes, 'off');
            app.CorrAxes.FontSize  = 8;
            app.CorrAxes.XColor    = C_MUTED;
            app.CorrAxes.YColor    = C_MUTED;
            app.CorrAxes.Color     = [1 1 1];
            app.CorrAxes.Box       = 'off';
            grid(app.CorrAxes, 'on');
            app.CorrAxes.GridColor = [0.90 0.90 0.93];

            % Etiqueta R encima del scatter
            if ~isnan(m.mainSeq_r)
                if m.mainSeq_r >= 0.70
                    rTxt = sprintf('r = %.2f  Normal',    m.mainSeq_r);
                    rCol = C_GREEN;
                elseif m.mainSeq_r >= 0.40
                    rTxt = sprintf('r = %.2f  Moderado',  m.mainSeq_r);
                    rCol = C_ORANGE;
                else
                    rTxt = sprintf('r = %.2f  Alterado',  m.mainSeq_r);
                    rCol = C_RED;
                end
                app.RresultLBL.Text      = rTxt;
                app.RresultLBL.FontColor = rCol;
                app.RresultLBL.FontWeight = 'bold';
            else
                if isfield(m, 'ms_reason') && ~isempty(m.ms_reason)
                    app.RresultLBL.Text = sprintf('Sin datos  (%s)', m.ms_reason);
                else
                    app.RresultLBL.Text = 'Sin datos suficientes';
                end
                app.RresultLBL.FontColor = C_MUTED;
            end

            % ════════════════════════════════════════════════════════════════════
            %  4. ASIMETRÍA — UIAxes2
            % ═══════════════════════════════════════════════════════════════════
            cla(app.AsimetriaAxes);
            hold(app.AsimetriaAxes, 'on');

            val = m.asymmetry_num;

            % Zonas de color de fondo
            patch(app.AsimetriaAxes, [-1.1 -0.25 -0.25 -1.1], [-1 -1 1 1], ...
                [1.0  0.93 0.88], 'EdgeColor', 'none', 'FaceAlpha', 0.8);
            patch(app.AsimetriaAxes, [-0.25 0.25 0.25 -0.25], [-1 -1 1 1], ...
                [0.88 1.0  0.92], 'EdgeColor', 'none', 'FaceAlpha', 0.8);
            patch(app.AsimetriaAxes, [0.25 1.1 1.1 0.25], [-1 -1 1 1], ...
                [1.0  0.93 0.88], 'EdgeColor', 'none', 'FaceAlpha', 0.8);

            % Líneas de referencia
            xline(app.AsimetriaAxes,  0,    '-',  'Color', [0.65 0.65 0.70], 'LineWidth', 0.8);
            xline(app.AsimetriaAxes,  0.25, '--', 'Color', [0.75 0.75 0.80], 'LineWidth', 0.7);
            xline(app.AsimetriaAxes, -0.25, '--', 'Color', [0.75 0.75 0.80], 'LineWidth', 0.7);

            % Barra de valor
            if abs(val) > 0.25
                barCol = C_ORANGE;
            else
                barCol = C_GREEN;
            end
            barh(app.AsimetriaAxes, 0, val, 0.65, 'FaceColor', barCol, 'EdgeColor', 'none');
            plot(app.AsimetriaAxes, val, 0.48, 'v', 'MarkerSize', 7, ...
                'MarkerFaceColor', barCol, 'MarkerEdgeColor', 'none');

            % Valor numérico
            text(app.AsimetriaAxes, val, 0.76, sprintf('%+.2f', val), ...
                'HorizontalAlignment', 'center', 'FontSize', 10, ...
                'FontWeight', 'bold', 'Color', barCol);

            % Conteos L/D
            text(app.AsimetriaAxes, -0.62, -0.72, sprintf('I: %d', m.num_left), ...
                'FontSize', 14, 'FontWeight', 'bold', ...
                'Color', C_PURPLE, 'HorizontalAlignment', 'center');
            text(app.AsimetriaAxes,  0.62, -0.72, sprintf('D: %d', m.num_right), ...
                'FontSize', 14, 'FontWeight', 'bold', ...
                'Color', C_CYAN, 'HorizontalAlignment', 'center');

            hold(app.AsimetriaAxes, 'off');
            app.AsimetriaAxes.XLim      = [-1.1 1.1];
            app.AsimetriaAxes.YLim      = [-1   1];
            app.AsimetriaAxes.YColor    = 'none';
            app.AsimetriaAxes.YTick     = [];
            app.AsimetriaAxes.XTick     = [-1 -0.25 0 0.25 1];
            app.AsimetriaAxes.XTickLabel = {'-1','−.25','0','+.25','+1'};
            app.AsimetriaAxes.FontSize  = 8;
            app.AsimetriaAxes.XColor    = C_MUTED;
            app.AsimetriaAxes.Color     = [1 1 1];
            app.AsimetriaAxes.Box       = 'off';

            % ════════════════════════════════════════════════════════════════════
            %  5. BOTONES DE MÉTRICAS CLAVE
            % ════════════════════════════════════════════════════════════════════
            % Color fijo identificativo por botón — independiente del valor

            C1 = [0.50 0.38 0.75];   % violeta suave  — Sacadas totales
            C2 = [0.24 0.60 0.47];   % salvia         — Duracion media
            C3 = [0.78 0.47 0.27];   % terracota      — Parpadeos
            C4 = [0.29 0.53 0.77];   % azul           — Fijacion media
            C5 = [0.60 0.41 0.63];   % malva          — Asimetria
            C6 = [0.23 0.57 0.66];   % teal           — Correlacion

            setMetricButton(app, app.SacadastotalesButton, ...
                'Sacadas totales', sprintf('%d', m.num_total), C1, BG_LAVANDA);

            setMetricButton(app, app.DurationmediaButton, ...
                'Duracion media', sprintf('%.0f ms', m.mean_duration), C2, BG_LAVANDA);

            setMetricButton(app, app.ParpadeosButton, ...
                'Parpadeos', sprintf('%d  (%.1f/min)', m.num_blinks, m.blink_rate), C3, BG_LAVANDA);

            setMetricButton(app, app.FijacionMediaButton, ...
                'Fijacion media', sprintf('%.0f ms', m.mean_fixation), C4, BG_LAVANDA);

            setMetricButton(app, app.AsimetriaButton, ...
                'Asimetria D/I', sprintf('%+.2f', m.asymmetry_num), C5, BG_LAVANDA);

            if ~isnan(m.mainSeq_r)
                corrTxt = sprintf('r = %.2f', m.mainSeq_r);
            else
                corrTxt = 'r = —';
            end
            setMetricButton(app, app.CorrelationButton, 'Correlacion', corrTxt, C6, BG_LAVANDA);

            % ════════════════════════════════════════════════════════════════════
            %  6. OBSERVACIONES — ObsHTML
            % ════════════════════════════════════════════════════════════════════
            % Alerta duracion
            alertaDur = '';
            if m.num_total > 0
                pct_long = sum(m.duration > 120) / m.num_total * 100;
                if pct_long > 20
                    alertaDur = sprintf('%.0f%% sacadas >120 ms', pct_long);
                end
            end

            % Estado Main Sequence
            if ~isnan(m.mainSeq_r)
                if m.mainSeq_r >= 0.70
                    msEstado = 'Normal';
                elseif m.mainSeq_r >= 0.40
                    msEstado = 'Moderado';
                else
                    msEstado = 'Alterado';
                end
                msR = sprintf('%.2f', m.mainSeq_r);
            else
                msEstado = 'Sin datos (n<3)';
                msR      = 'NaN';
            end

            % Estado parpadeo
            if m.blink_rate < 8
                blinkEstado = 'Posible fatiga';
            elseif m.blink_rate > 25
                blinkEstado = 'Posible irritacion';
            else
                blinkEstado = 'Normal';
            end

            % Estado asimetria
            if m.asymmetry_num > 0.25
                asimTexto = 'Predominio DERECHA';
            elseif m.asymmetry_num < -0.25
                asimTexto = 'Predominio IZQUIERDA';
            else
                asimTexto = 'Equilibrado';
            end

            % Empaquetar struct para el HTML
            obsData = struct( ...
                'dur_media',   sprintf('%.1f ms',   m.mean_duration),  ...
                'dur_val',     m.mean_duration,                        ...
                'amp_media',   sprintf('%.1f mV',   m.mean_amplitude), ...
                'vel_media',   sprintf('%.1f mV/s', m.mean_velocity),  ...
                'vel_der',     sprintf('%.1f mV/s', m.mean_velocity_r),...
                'vel_izq',     sprintf('%.1f mV/s', m.mean_velocity_l),...
                'fix_media',   sprintf('%.0f ms',   m.mean_fixation),  ...
                'fix_val',     m.mean_fixation,                        ...
                'ms_r',        msR,                                    ...
                'ms_val',      m.mainSeq_r,                            ...
                'ms_estado',   msEstado,                               ...
                'asim_idx',    sprintf('%+.2f', m.asymmetry_num),      ...
                'asim_val',    m.asymmetry_num,                        ...
                'asim_texto',  asimTexto,                              ...
                'blink_rate',  sprintf('%.1f /min', m.blink_rate),     ...
                'blink_val',   m.blink_rate,                           ...
                'blink_estado',blinkEstado,                            ...
                'alerta_dur',  alertaDur                               ...
                );

            app.ObsHTML.Data = obsData;
        end
        function setMetricButton(~,btn, nombre, valor, colorValor, bgColor)
            % SETMETRICBUTTON  Formatea un uibutton como tarjeta de métrica.
            %   btn        — handle del uibutton
            %   nombre     — texto superior (nombre de la métrica)
            %   valor      — texto inferior (valor calculado)
            %   colorValor — RGB del texto del valor (verde/naranja/rojo)
            %   bgColor    — RGB del fondo del botón

            btn.Text            = sprintf('%s\n%s', nombre, valor);
            btn.BackgroundColor = bgColor;
            btn.FontColor       = colorValor;
            btn.FontWeight      = 'bold';
            btn.FontSize        = 14;
            btn.WordWrap        = 'on';
            btn.HorizontalAlignment = 'center';
        end

        function actualizarTablaEventosSacadas(app)
            if isempty(app.SaccadesMetrics) || isempty(app.SaccadesMetrics.eventsTable)
                app.EventsTable.Data = table();
                return
            end

            T = app.SaccadesMetrics.eventsTable;

            % Seleccionar y renombrar columnas para visualización clínica
            Tview = table( ...
                round(T.onsetTime,  3), ...
                round(T.offsetTime, 3), ...
                round(T.duration_ms, 1), ...
                round(abs(T.amplitude_mV), 2), ...
                round(T.peakVel_mVs, 1), ...
                T.direction, ...
                'VariableNames', { ...
                'Inicio_s', 'Fin_s', 'Duracion_ms', ...
                'Amplitud_mV', 'VelPico_mVs', 'Direccion'});
            app.EventsTable.Data = Tview;
            app.EventsTable.ColumnName = { ...
                'Inicio (s)', 'Fin (s)', 'Duración (ms)', ...
                'Amplitud (mV)', 'Vel. pico (mV/s)', 'Dirección'};
            % --- Colorear filas según dirección ---
            nRows = size(Tview,1);
            for r = 1:nRows
                sBG = uistyle;
                sBG.FontColor = [0 0 0]; % texto negro

                % Colores suaves por dirección
                if strcmpi(T.direction{r}, 'izquierda')
                    sBG.BackgroundColor = [1 0.8 0.8]; % rojo suave
                elseif strcmpi(T.direction{r}, 'derecha')
                    sBG.BackgroundColor = [0.8 0.9 1]; % azul suave
                else
                    sBG.BackgroundColor = [1 1 1]; % blanco
                end

                addStyle(app.EventsTable, sBG, "row", r);
            end
        end

        function ResetSignals(app)
            if ~isempty(app.TimerGraficar) && isvalid(app.TimerGraficar)
                stop(app.TimerGraficar);
                delete(app.TimerGraficar);
                app.TimerGraficar = [];
            end
            % Flush del buffer antes de empezar (descarta datos acumulados)
            try
                flush(app.Device);
            catch
            end
            % Reset fs state for new recording session
            app.fs_calibrating = true;
            app.fs_fixed       = [];
            app.fs_est         = 0;
            app.lastTime       = [];
            app.counter_fs     = 0;

            app.FilterState = struct();

            % Limpiar líneas animadas anteriores si existen
            if ~isempty(app.LineSHEOL) && isvalid(app.LineSHEOL)
                delete(app.LineSHEOL);
            end
            if ~isempty(app.LineSVEOL) && isvalid(app.LineSVEOL)
                delete(app.LineSVEOL);
            end
            if ~isempty(app.LineSHEOR) && isvalid(app.LineSHEOR)
                delete(app.LineSHEOR);
            end
            if ~isempty(app.LineSVEOR) && isvalid(app.LineSVEOR)
                delete(app.LineSVEOR);
            end
            app.AnalizarMedidasHTML.Visible = "off";
            app.SHEOL = [];
            app.SVEOL = [];
            app.SHEOR = [];
            app.SVEOR = [];

            app.SHEOL_f = [];
            app.SVEOL_f = [];
            app.SHEOR_f = [];
            app.SVEOR_f = [];
            app.SignalsFiltered_OK = false;

            app.HEOG_global = [];
            app.VEOG_global = [];
            app.SaccadesMetrics = [];
        end
        function saved = saveSignals(app)
            saved = false;

            if isempty(app.SHEOL) && isempty(app.SVEOL) && isempty(app.SHEOR) && isempty(app.SVEOR) && ~app.SignalsFiltered_OK
                uialert(app.UIFigure, 'No signals to save.', 'Save', 'Icon', 'warning');
                return
            end

            [file, path] = uiputfile('*.mat', 'Save EOG signals', ...
                sprintf('EOG_%s.mat', datestr(now, 'yyyymmdd_HHMMSS')));

            if isequal(file, 0)
                return  % user cancelled -> saved stays false
            end

            EOG        = struct();
            EOG.fs     = app.fs_fixed;
            EOG.date   = datestr(now);
            EOG.raw.SHEOL = app.SHEOL;
            EOG.raw.SVEOL = app.SVEOL;
            EOG.raw.SHEOR = app.SHEOR;
            EOG.raw.SVEOR = app.SVEOR;

            if app.SignalsFiltered_OK
                EOG.filtered.SHEOL_f = app.SHEOL_f;
                EOG.filtered.SVEOL_f = app.SVEOL_f;
                EOG.filtered.SHEOR_f = app.SHEOR_f;
                EOG.filtered.SVEOR_f = app.SVEOR_f;
            end

            save(fullfile(path, file), 'EOG');
            uialert(app.UIFigure, sprintf('Saved to:\n%s', fullfile(path, file)), ...
                'Saved', 'Icon', 'success');
            saved = true;
        end
        function cargarDebugSignals(app)
            % CARGARDEBUG  Carga un .mat guardado con saveSignals y simula
            % haber pulsado "Analizar Medidas" (AnalisisSignalsScreen + graficas + metricas).

            % ── Seleccionar fichero ───────────────────────────────────────────────
            [file, path] = uigetfile('*.mat', 'Cargar señales EOG guardadas');
            if isequal(file, 0)
                return
            end

            data = load(fullfile(path, file));

            if ~isfield(data, 'EOG')
                uialert(app.UIFigure, ...
                    'El fichero no contiene una variable EOG valida.', ...
                    'Error de carga', 'Icon', 'error');
                return
            end

            EOG = data.EOG;

            if ~isfield(EOG, 'fs') || ~isfield(EOG, 'raw')
                uialert(app.UIFigure, ...
                    'Formato de fichero incorrecto.', ...
                    'Error', 'Icon', 'error');
                return
            end

            % ── Restaurar estado ──────────────────────────────────────────────────
            app.fs_fixed       = EOG.fs;
            app.fs_calibrating = false;
            app.fs_est         = EOG.fs;

            app.SHEOL = EOG.raw.SHEOL;
            app.SVEOL = EOG.raw.SVEOL;
            app.SHEOR = EOG.raw.SHEOR;
            app.SVEOR = EOG.raw.SVEOR;

            if isfield(EOG, 'filtered') && isfield(EOG.filtered, 'SHEOL_f') ...
                    && ~isempty(EOG.filtered.SHEOL_f)
                app.SHEOL_f = EOG.filtered.SHEOL_f;
                app.SVEOL_f = EOG.filtered.SVEOL_f;
                app.SHEOR_f = EOG.filtered.SHEOR_f;
                app.SVEOR_f = EOG.filtered.SVEOR_f;
                app.SignalsFiltered_OK = true;
                fprintf('[DEBUG] Senales filtradas cargadas desde fichero.\n');
            else
                app.SignalsFiltered_OK = false;
                filterSignalsPost(app);
                fprintf('[DEBUG] filterSignalsPost ejecutado sobre crudas.\n');
            end

            app.SaccadesMetrics = [];
            app.HEOG_global     = [];
            app.VEOG_global     = [];

            if ~app.SignalsFiltered_OK
                uialert(app.UIFigure, ...
                    'El filtrado fallo. Comprueba los datos cargados.', ...
                    'Error de filtrado', 'Icon', 'warning');
                return
            end

            fprintf('[DEBUG] fs=%.1f Hz | muestras=%d | duracion=%.1f s\n', ...
                app.fs_fixed, length(app.SHEOR_f), length(app.SHEOR_f)/app.fs_fixed);

            % ── Simular click en "Analizar Medidas" ───────────────────────────────
            AnalizarMedidasClicked(app);
        end
        function exportCSV(app)
            % EXPORTCSV  Exporta el análisis EOG completo en un único fichero CSV.
            %
            % Estructura del fichero (secciones separadas por filas de comentario #):
            %
            %   [SESION]      — parámetros globales de la grabación
            %   [METRICAS]    — métricas clínicas calculadas
            %   [SACADAS]     — tabla evento×evento de cada sacada
            %   [PARPADEOS]   — tabla evento×evento de cada parpadeo
            %   [FIJACIONES]  — tabla evento×evento de cada fijación
            %
            % Cada sección empieza con una fila  # SECCION: <nombre>
            % y sus columnas propias, por lo que se puede parsear programáticamente
            % buscando esa etiqueta.

            % ── Validación ────────────────────────────────────────────────────────
            if ~app.SignalsFiltered_OK
                uialert(app.UIFigure, ...
                    'Primero debes adquirir, filtrar y analizar una señal.', ...
                    'Sin datos para exportar', 'Icon', 'warning');
                return
            end
            if isempty(app.SaccadesMetrics)
                uialert(app.UIFigure, ...
                    'Primero ejecuta el análisis (botón "Analizar Medidas").', ...
                    'Sin métricas calculadas', 'Icon', 'warning');
                return
            end

            m = app.SaccadesMetrics;

            % ── Diálogo de guardado ───────────────────────────────────────────────
            timestamp   = datestr(now, 'yyyymmdd_HHMMSS');
            defaultName = sprintf('HORUS_%s_%s.csv', string(app.usuario), timestamp);

            [file, folder] = uiputfile('*.csv', ...
                'Guardar análisis HORUS como CSV', defaultName);
            if isequal(file, 0)
                return
            end

            filePath = fullfile(folder, file);
            fid = fopen(filePath, 'w', 'n', 'UTF-8');
            if fid == -1
                uialert(app.UIFigure, ...
                    sprintf('No se puede escribir en:\n%s', filePath), ...
                    'Error de escritura', 'Icon', 'error');
                return
            end

            try
                N_muestras = length(app.SHEOR_f);
                duracion_s = N_muestras / app.fs_fixed;

                % ══════════════════════════════════════════════════════════════════
                %  CABECERA GLOBAL
                % ══════════════════════════════════════════════════════════════════
                fprintf(fid, '# HORUS EOG — Exportacion completa de sesion\n');
                fprintf(fid, '# Exportado:,%s\n', ...
                    datestr(now, 'dd/mm/yyyy HH:MM:SS'));
                fprintf(fid, '#\n');
                fprintf(fid, '# Cada seccion comienza con:  # SECCION: <nombre>\n');
                fprintf(fid, '# seguida de una fila de cabeceras y los datos.\n');
                fprintf(fid, '#\n');

                % ══════════════════════════════════════════════════════════════════
                %  SECCIÓN 1 — DATOS DE SESIÓN
                % ══════════════════════════════════════════════════════════════════
                fprintf(fid, '# SECCION: SESION\n');
                fprintf(fid, 'Parametro,Valor,Unidad\n');
                fprintf(fid, 'Usuario,%s,\n',                   string(app.usuario));
                fprintf(fid, 'Frecuencia_muestreo,%.1f,Hz\n',   app.fs_fixed);
                fprintf(fid, 'Duracion_registro,%.2f,s\n',      duracion_s);
                fprintf(fid, 'Muestras_por_canal,%d,\n',        N_muestras);
                fprintf(fid, '#\n');

                % ══════════════════════════════════════════════════════════════════
                %  SECCIÓN 2 — MÉTRICAS CLÍNICAS GLOBALES
                % ══════════════════════════════════════════════════════════════════
                fprintf(fid, '# SECCION: METRICAS\n');
                fprintf(fid, 'Parametro,Valor,Unidad,Interpretacion\n');

                % — Sacadas generales —
                fprintf(fid, 'Sacadas_totales,%d,,\n',              m.num_total);
                fprintf(fid, 'Sacadas_derecha,%d,,\n',              m.num_right);
                fprintf(fid, 'Sacadas_izquierda,%d,,\n',            m.num_left);
                fprintf(fid, 'Frecuencia_sacadas,%.2f,sac/min,\n',  m.frequency_min);
                fprintf(fid, 'Duracion_media,%.1f,ms,\n',           m.mean_duration);
                fprintf(fid, 'Amplitud_media,%.2f,mV,\n',           m.mean_amplitude);
                fprintf(fid, 'Vel_pico_media,%.1f,mV/s,\n',         m.mean_velocity);
                fprintf(fid, 'Vel_pico_media_derecha,%.1f,mV/s,\n', m.mean_velocity_r);
                fprintf(fid, 'Vel_pico_media_izquierda,%.1f,mV/s,\n', m.mean_velocity_l);
                fprintf(fid, 'Umbral_velocidad_T_high,%.1f,mV/s,\n', m.threshold_vel);

                % — Asimetría —
                if m.asymmetry_num > 0.25
                    asimStr = 'Predominio DERECHA';
                elseif m.asymmetry_num < -0.25
                    asimStr = 'Predominio IZQUIERDA';
                else
                    asimStr = 'Equilibrado';
                end
                fprintf(fid, 'Asimetria_DI,%.3f,[-1 a 1],%s\n', ...
                    m.asymmetry_num, asimStr);

                % — Clasificación leve/fuerte —
                if ~isempty(m.eventsTable) && m.num_total > 0
                    T        = m.eventsTable;
                    strongTh = median(abs(T.peakVel_mVs));
                    idxL     = strcmp(string(T.direction), 'izquierda');
                    idxR     = strcmp(string(T.direction), 'derecha');
                    fprintf(fid, 'Umbral_leve_fuerte,%.1f,mV/s,mediana vel. pico\n', strongTh);
                    fprintf(fid, 'Sacadas_leves_izquierda,%d,,\n',   sum(idxL & abs(T.peakVel_mVs) <  strongTh));
                    fprintf(fid, 'Sacadas_fuertes_izquierda,%d,,\n', sum(idxL & abs(T.peakVel_mVs) >= strongTh));
                    fprintf(fid, 'Sacadas_leves_derecha,%d,,\n',     sum(idxR & abs(T.peakVel_mVs) <  strongTh));
                    fprintf(fid, 'Sacadas_fuertes_derecha,%d,,\n',   sum(idxR & abs(T.peakVel_mVs) >= strongTh));
                end

                % — Main sequence —
                if ~isnan(m.mainSeq_r)
                    if m.mainSeq_r >= 0.70
                        msInterp = 'Normal (r>=0.70)';
                    elseif m.mainSeq_r >= 0.40
                        msInterp = 'Moderado (0.40<=r<0.70)';
                    else
                        msInterp = 'Alterado (r<0.40)';
                    end
                    fprintf(fid, 'MainSequence_r,%.4f,,%s\n',    m.mainSeq_r, msInterp);
                    fprintf(fid, 'MainSequence_p,%.4f,,\n',       m.mainSeq_p);
                    if ~isnan(m.mainSeq_slope)
                        fprintf(fid, 'MainSequence_slope,%.4f,log-log,\n', m.mainSeq_slope);
                    end
                else
                    fprintf(fid, 'MainSequence_r,NaN,,Insuficientes sacadas (<3)\n');
                end

                % — Parpadeos —
                if m.blink_rate < 8
                    blinkInterp = 'Posible fatiga (<8/min)';
                elseif m.blink_rate > 25
                    blinkInterp = 'Posible irritacion (>25/min)';
                else
                    blinkInterp = 'Normal (8-25/min)';
                end
                fprintf(fid, 'Parpadeos_totales,%d,,\n',          m.num_blinks);
                fprintf(fid, 'Tasa_parpadeo,%.1f,parl/min,%s\n',  m.blink_rate, blinkInterp);

                % — Fijaciones —
                fprintf(fid, 'Fijaciones_totales,%d,,\n',            m.num_fix);
                fprintf(fid, 'Duracion_media_fijacion,%.0f,ms,\n',   m.mean_fixation);
                fprintf(fid, '#\n');

                % ══════════════════════════════════════════════════════════════════
                %  SECCIÓN 3 — TABLA DE SACADAS
                % ══════════════════════════════════════════════════════════════════
                fprintf(fid, '# SECCION: SACADAS\n');
                fprintf(fid, ['Num,Onset_s,Offset_s,PicoVel_s,' ...
                    'Duracion_ms,Amplitud_abs_mV,' ...
                    'Amplitud_signed_mV,VelPico_mVs,Direccion\n']);

                if ~isempty(m.eventsTable) && m.num_total > 0
                    T = m.eventsTable;
                    for k = 1:m.num_total
                        fprintf(fid, '%d,%.3f,%.3f,%.3f,%.1f,%.2f,%.2f,%.1f,%s\n', ...
                            k, ...
                            T.onsetTime(k), ...
                            T.offsetTime(k), ...
                            T.peakTime(k), ...
                            T.duration_ms(k), ...
                            abs(T.amplitude_mV(k)), ...
                            T.amplitude_mV(k), ...
                            T.peakVel_mVs(k), ...
                            T.direction(k));
                    end
                else
                    fprintf(fid, '# (sin sacadas detectadas)\n');
                end
                fprintf(fid, '#\n');

                % ══════════════════════════════════════════════════════════════════
                %  SECCIÓN 4 — TABLA DE PARPADEOS
                % ══════════════════════════════════════════════════════════════════
                fprintf(fid, '# SECCION: PARPADEOS\n');
                fprintf(fid, 'Num,Onset_s,Offset_s,Duracion_ms,Amplitud_VEOG_mV\n');

                if ~isempty(m.blinksTable) && m.num_blinks > 0
                    B = m.blinksTable;
                    for k = 1:m.num_blinks
                        fprintf(fid, '%d,%.3f,%.3f,%.1f,%.1f\n', ...
                            k, ...
                            B.onsetTime(k), ...
                            B.offsetTime(k), ...
                            B.duration_ms(k), ...
                            B.amplitude_mV(k));
                    end
                else
                    fprintf(fid, '# (sin parpadeos detectados)\n');
                end
                fprintf(fid, '#\n');

                % ══════════════════════════════════════════════════════════════════
                %  SECCIÓN 5 — TABLA DE FIJACIONES
                % ══════════════════════════════════════════════════════════════════
                fprintf(fid, '# SECCION: FIJACIONES\n');
                fprintf(fid, 'Num,Onset_s,Offset_s,Duracion_ms\n');

                if ~isempty(m.fixTable) && m.num_fix > 0
                    F = m.fixTable;
                    for k = 1:m.num_fix
                        fprintf(fid, '%d,%.3f,%.3f,%.1f\n', ...
                            k, ...
                            F.onsetTime(k), ...
                            F.offsetTime(k), ...
                            F.duration_ms(k));
                    end
                else
                    fprintf(fid, '# (sin fijaciones detectadas)\n');
                end
                fprintf(fid, '#\n');

                % ── Cierre y confirmación ─────────────────────────────────────────
                fclose(fid);

                uialert(app.UIFigure, ...
                    sprintf(['Exportación completada.\n\n' ...
                    '%s\n\n' ...
                    '· %d sacadas\n' ...
                    '· %d parpadeos\n' ...
                    '· %d fijaciones'], ...
                    filePath, m.num_total, m.num_blinks, m.num_fix), ...
                    'CSV exportado', 'Icon', 'success');

                fprintf('✓ CSV unificado guardado en: %s\n', filePath);

            catch ME
                fclose(fid);
                uialert(app.UIFigure, ...
                    sprintf('Error durante la exportación:\n%s', ME.message), ...
                    'Error CSV', 'Icon', 'error');
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            app.SignalViewSlider.Visible = 'off';
            screenSize = get(0, 'ScreenSize');   % [left bottom width height
            % Get app window size
            figWidth  = app.UIFigure.Position(3);
            figHeight = app.UIFigure.Position(4);
            % Compute centered position
            posX = (screenSize(3) - figWidth) / 2;
            posY = (screenSize(4) - figHeight) / 2;
            % Update UIFigure position
            app.UIFigure.Position(1) = posX;
            app.UIFigure.Position(2) = posY;
            BackButtons = [...
                app.BackChooseActionHTML,...
                app.BackConectHTML,...
                app.BackGraficasHTML,...
                app.BackExpandedViewHTML,...
                app.BackAnalisisHTML,...
                app.BackEventsAnalisisHTML,...
                app.BackAnalisisSignalsHTML
                ];
            for bb = BackButtons
                bb.HTMLSource = 'GoBack.html';
            end
            app.BackChooseActionHTML.Data = struct('id', 'ChooseActionScreen');
            app.BackConectHTML.Data = struct('id', 'ConectScreen');
            app.BackGraficasHTML.Data = struct('id', 'GraficasScreen');
            app.BackExpandedViewHTML.Data = struct('id', 'ExpandedViewScreen');
            app.BackAnalisisHTML.Data = struct('id', 'AnalisisScreen');
            app.BackEventsAnalisisHTML.Data = struct('id', 'EventsAnalisisScreen');
            app.BackAnalisisSignalsHTML.Data = struct('id', 'AnalisisSignalsScreen');

            app.PasswordHTML.HTMLSource = 'TextField.html';
            app.PasswordHTML.Data = struct('type', 'password', 'label', 'Contraseña');
            app.UserNameHTML.HTMLSource = 'TextField.html';
            app.UserNameHTML.Data = struct('type', 'text', 'label', 'Usuario');

            app.WifiStatusHTML.HTMLSource= 'wifi_status.html';
            app.ObsHTML.HTMLSource    = 'observaciones.html';
            app.DevicesListHTML.HTMLSource = 'device_list.html';
            app.LogoHorusHTML.HTMLSource = 'logo_horus.html';
            app.LogoHorusHTML.Data = struct('color', '#808080');
            app.BuscarDevicesHTML.HTMLSource = 'buscar_dispositivo_button.html';
            app.ExportHTML.HTMLSource = "export_button.html";

            buttons = [...
                app.TomarMedidasHTML,...
                app.AccederHTML,...
                app.ConectarGafasHTML,...
                app.VerAnalisisPreviosHTML,...
                app.AnalizarMedidasHTML,...
                app.ResultadosAnalisisHTML,...
                app.VerticalLExpandedHTML,...
                app.VerticalRExpandedHTML,...
                app.HorizontalLExpandedHTML,...
                app.HorizontalRExpandedHTML,...
                app.AnalisisEventosHTML,...
                app.StartStopHTML
                ];
            for b = buttons
                b.HTMLSource = 'Button.html';
            end

            app.StartStopHTML.Data = app.StartAppearance;
            app.AccederHTML.Data = struct( ...
                'bgColor',      '#afeeee', ...
                'textColor',    '#ffffff', ...
                'borderColor',  '#ecffff', ...
                'borderWidth',  '2px',     ...
                'borderRadius', '26px',    ...
                'label',        'Acceder' ...
                );
            app.AnalizarMedidasHTML.Data = struct( ...
                'bgColor',      '#a0c4ff', ...
                'textColor',    '#4b0082', ...
                'borderColor',  '#a0c4ff', ...
                'borderWidth',  '0',     ...
                'borderRadius', '14px',    ...
                'label',        'Analizar Medidas' ...
                );
            app.VerticalRExpandedHTML.Data = struct( ...
                'bgColor',      '#b3f0c8', ...
                'textColor',    '#0a5c28', ...
                'borderColor',  '#b3f0c8', ...
                'borderWidth',  '0',     ...
                'borderRadius', '14px',    ...
                'label',        'R Vertical +' ...
                );
            app.HorizontalRExpandedHTML.Data = struct( ...
                'bgColor',      '#b3eaf5', ...
                'textColor',    '#006a80', ...
                'borderColor',  '#b3eaf5', ...
                'borderWidth',  '0',     ...
                'borderRadius', '14px',    ...
                'label',        'R Horizontal +' ...
                );
            app.HorizontalLExpandedHTML.Data = struct( ...
                'bgColor',      '#d9b3f5', ...
                'textColor',    '#3d0070', ...
                'borderColor',  '#d9b3f5', ...
                'borderWidth',  '0',     ...
                'borderRadius', '14px',    ...
                'label',        'L Horizontal +' ...
                );
            app.VerticalLExpandedHTML.Data = struct( ...
                'bgColor',      '#f5bcbc', ...
                'textColor',    '#8b1a1a', ...
                'borderColor',  '#f5bcbc', ...
                'borderWidth',  '0',     ...
                'borderRadius', '14px',    ...
                'label',        'L Vertical +' ...
                );
            app.ResultadosAnalisisHTML.Data = struct( ...
                'bgColor',      '#e3ffe7', ...
                'textColor',    '#4b0082', ...
                'borderColor',  '#bb99ff', ...
                'borderWidth',  '2px',     ...
                'borderRadius', '20px',    ...
                'label',        'Resultados Analisis' ...
                );
            app.TomarMedidasHTML.Data = struct( ...
                'bgColor',      '#afeeee', ...
                'textColor',    '#6a06b3', ...
                'borderColor',  '#9370db', ...
                'borderWidth',  '2px',     ...
                'borderRadius', '18px',    ...
                'label',        'Tomar Medidas' ...
                );
            app.ConectarGafasHTML.Data = struct( ...
                'bgColor',      '#a0c4ff', ...
                'textColor',    '#4b0082', ...
                'borderColor',  '#a0c4ff', ...
                'borderWidth',  '0',     ...
                'borderRadius', '26px',    ...
                'label',        'Conectar Gafas Horus' ...
                );
            app.AnalisisEventosHTML.Data = struct( ...
                'bgColor',      '#cce0ff', ...
                'textColor',    '#ffffff', ...
                'borderColor',  '#cce0ff', ...
                'borderWidth',  '0',     ...
                'borderRadius', '16px',    ...
                'label',        'Analisis Eventos' ...
                );
            app.VerAnalisisPreviosHTML.Data = struct( ...
                'bgColor',      '#a0c4ff', ...
                'textColor',    '#4b0082', ...
                'borderColor',  '#a0c4ff', ...
                'borderWidth',  '0',     ...
                'borderRadius', '26px',    ...
                'label',        'Ver Analisis Previos' ...
                );
            app.EventsTable.ColumnName = { ...
                'Inicio (s)', 'Fin (s)', 'Duración (ms)', ...
                'Amplitud (mV)', 'Vel. pico (mV/s)', 'Dirección'};
            ChangeWindowTo(app,app.MainScreen);
            drawnow
            pause(0.8);
            app.UIFigure.Visible = 'on';
            %cargarDebugSignals(app);
        end

        % Value changed function: ShowHiddenWifisCheckBox
        function ShowHiddenWifisClicked(app, event)
            value = app.ShowHiddenWifisCheckBox.Value;
            if value == 1
                ReloadListWifis(app,app.AvailableWifis);
            else
                ReloadListWifis(app,app.DevicesWifis);
            end
        end

        % Event received function: DevicesListHTML
        function DevicesListClicked(app, event)
            eventData = event.HTMLEventData;
            value = eventData.name;
            ChangeWifiStatus(app,'conectando');
            app.DevicesListHTML.Data = struct('action', 'setState', 'name', value, 'state', 'connecting');
            ConectingWifi = uiprogressdlg(app.UIFigure, ...
                'Title', 'Conectando...', 'Message', 'Conectandose al Dispositivo', 'Indeterminate', 'on');
            drawnow
            for p = app.PasswordOptions
                ConectingWifi.Message = 'Probando Contraseña: ' + p;
                createWifiProfile(app,value,p);

                system("netsh wlan connect name=""" + value + """");

                timeout = 8;
                t0 = tic;

                while toc(t0) < timeout

                    [~,out] = system('netsh wlan show interfaces');

                    % comprobar estado
                    stateLine = regexp(out,'Estado\s*:\s*([^\r\n]*)','tokens','once');

                    % comprobar SSID
                    ssidLine = regexp(out,'SSID\s*:\s*([^\r\n]*)','tokens','once');

                    if ~isempty(stateLine) && ~isempty(ssidLine)

                        state = strtrim(lower(stateLine{1}));
                        currentSSID = strtrim(ssidLine{1});
                        disp("Estado: " + state)
                        disp("SSID: " + currentSSID)

                        if (strcmp(state,"conectado") || strcmp(state,"connected")) && strcmp(currentSSID,value)

                            disp("Conectado con: " + p);
                            close(ConectingWifi);
                            uialert(app.UIFigure,'Dispositivo Conectado','Conexion Hecha','Icon','success');
                            app.TomarMedidasHTML.Visible = 'on';
                            ChangeWifiStatus(app,'conectado');
                            app.LogoHorusHTML.Data = struct('color', '#95F18B');
                            app.DevicesListHTML.Data = struct('action', 'setState', 'name', value, 'state', 'connected');
                            return

                        end

                    end

                    pause(0.3)

                end
                ChangeWifiStatus(app,'error');
                app.LogoHorusHTML.Data = struct('color', '#FF3333');
            end

        end

        % Event received function: BuscarDevicesHTML
        function BuscarDevicesClicked(app, event)
            if strcmp(event.HTMLEventName, "ButtonClicked")
                RefreshWifis(app);
            end
        end

        % Event received function: AccederHTML, AnalisisEventosHTML, 
        % ...and 10 other components
        function ButtonClicked(app, event)
            eventName = event.HTMLEventName;
            eventData = event.HTMLEventData;
            if strcmp(eventName, 'ButtonClicked')
                if strcmp(eventData, 'Tomar Medidas')
                    TomarMedidasClicked(app);
                elseif strcmp(eventData, 'Acceder')
                    AccederClicked(app);
                elseif strcmp(eventData, 'Analizar Medidas')
                    AnalizarMedidasClicked(app);
                elseif strcmp(eventData, 'Resultados Analisis')
                    ResultadosAnalisisClicked(app);
                elseif strcmp(eventData, 'Conectar Gafas Horus')
                    ConectarGafasClicked(app);
                elseif strcmp(eventData, 'Ver Analisis Previos')
                    VerAnalisisPreviosClicked(app);
                elseif strcmp(eventData, 'Analisis Eventos')
                    AnalisisEventosClicked(app);
                elseif strcmp(eventData, 'R Horizontal +')
                    ExpandSignalView(app,eventData);
                elseif strcmp(eventData, 'R Vertical +')
                    ExpandSignalView(app,eventData);
                elseif strcmp(eventData, 'L Horizontal +')
                    ExpandSignalView(app,eventData);
                elseif strcmp(eventData, 'L Vertical +')
                    ExpandSignalView(app,eventData);
                elseif strcmp(eventData, 'Start') | strcmp(eventData, 'Stop')
                    StartStopClicked(app,eventData);
                end
            end
        end

        % Event received function: BackAnalisisHTML, 
        % ...and 6 other components
        function BackButtonClicked(app, event)
            eventName = event.HTMLEventName;
            eventData = event.HTMLEventData.id;
            disp(eventData);
            if strcmp(eventName, 'buttonClicked')
                if strcmp(eventData, 'ChooseActionScreen')
                    BackChooseActionClicked(app);
                elseif strcmp(eventData, 'ConectScreen')
                    BackConectClicked(app);
                elseif strcmp(eventData, 'GraficasScreen')
                    BackGraficasClicked(app);
                elseif strcmp(eventData, 'ExpandedViewScreen')
                    BackExpandedViewClicked(app);
                elseif strcmp(eventData, 'AnalisisScreen')
                    BackAnalisisClicked(app);
                elseif strcmp(eventData, 'EventsAnalisisScreen')
                    BackEventsAnalisisClicked(app);
                elseif strcmp(eventData, 'AnalisisSignalsScreen')
                    BackAnalisisSignalsClicked(app);
                end
            end
        end

        % Close request function: UIFigure
        function closeApp(app, event)
            if ~isempty(app.SHEOL) || ~isempty(app.SVEOL) || ~isempty(app.SHEOR) || ~isempty(app.SVEOR)
                selection = uiconfirm(app.UIFigure, ...
                    'Tienes señales tomadas no procesadas. ¿Quieres guardarlas antes de cerrar?', ...
                    'Guardar señales tomadas', ...
                    'Options',       {'Guardar', 'Cerrar sin guardar', 'Cancelar'}, ...
                    'DefaultOption', 'Guardar', ...
                    'CancelOption',  'Cancelar', ...
                    'Icon',          'question');

                switch selection
                    case 'Guardar'
                        saved = saveSignals(app);
                        if ~saved
                            return  % user cancelled file dialog, abort close
                        end
                    case 'Cancelar'
                        return
                    case 'Cerrar sin guardar'
                        % continue to close
                end
            end

            if ~isempty(app.TimerGraficar) && isvalid(app.TimerGraficar)
                stop(app.TimerGraficar);
                delete(app.TimerGraficar);
            end
            delete(app)
        end

        % Event received function: ExportHTML
        function ExportClicked(app, event)
            format = event.HTMLEventData;
            switch format
                case 'csv'
                    exportCSV(app);
                case 'pdf'
                    disp('Exportar como PDF')
            end
        end

        % Callback function: SignalViewSlider, SignalViewSlider
        function SliderMoving(app, event)
            startT  = event.Value;
            winSize = min(app.ExpandedViewWindow, ...
                app.ExpandedSignalTime(end) - startT);
            app.ExpandedPlot.XLim = [startT, startT + winSize];
        end

        % Callback function: CrearCuentaLink
        function CrearCuentaClicked(app, event)
            if app.crearcuenta

                app.SignInStateLBL.Text = "Ingrese sus datos";
                app.SignInLBL.Text = "Iniciar sesión";
                app.CrearCuentaLink.Text = "Crear cuenta";
                app.crearcuenta = false;
            else
                app.SignInStateLBL.Text = "Elija el su nombre de usuario y contraseña";
                app.SignInLBL.Text = "Crear Cuenta";
                app.CrearCuentaLink.Text = "Iniciar Sesión";
                app.crearcuenta = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeApp, true);
            app.ChooseActionScreen = uipanel(app.UIFigure);
            app.ChooseActionGrid = uigridlayout(app.ChooseActionScreen);
            app.Image = uiimage(app.ChooseActionGrid);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'GAFAS-HORUS-LOGO.svg');
            app.Image2 = uiimage(app.ChooseActionGrid);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'patient.svg');
            app.VerAnalisisPreviosHTML = uihtml(app.ChooseActionGrid);
            app.VerAnalisisPreviosHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.ConectarGafasHTML = uihtml(app.ChooseActionGrid);
            app.ConectarGafasHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.BackChooseActionHTML = uihtml(app.ChooseActionGrid);
            app.BackChooseActionHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.EventsAnalisisScreen = uipanel(app.UIFigure);
            app.EventsAnalisisGrid = uigridlayout(app.EventsAnalisisScreen);
            app.EventsAnalisisLBL = uilabel(app.EventsAnalisisGrid);
            app.EventsAnalisisLBL.Text = 'Analisis de Eventos Oculares';
            app.EventsTable = uitable(app.EventsAnalisisGrid);
            app.EventsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.EventsTable.RowName = {};
            app.BackEventsAnalisisHTML = uihtml(app.EventsAnalisisGrid);
            app.BackEventsAnalisisHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.GraficasScreen = uipanel(app.UIFigure);
            app.GraficasGrid = uigridlayout(app.GraficasScreen);
            app.VEOL = uiaxes(app.GraficasGrid);
            title(app.VEOL, 'VEOL')
            zlabel(app.VEOL, 'Z')
            app.HEOR = uiaxes(app.GraficasGrid);
            title(app.HEOR, 'HEOR')
            zlabel(app.HEOR, 'Z')
            app.HEOL = uiaxes(app.GraficasGrid);
            title(app.HEOL, 'HEOL')
            zlabel(app.HEOL, 'Z')
            app.VEOR = uiaxes(app.GraficasGrid);
            title(app.VEOR, 'VEOR')
            zlabel(app.VEOR, 'Z')
            app.ConectedLampGrid = uigridlayout(app.GraficasGrid);
            app.ConectedLBL = uilabel(app.ConectedLampGrid);
            app.ConectedLBL.Text = 'Conectado';
            app.ConectedLamp = uilamp(app.ConectedLampGrid);
            app.EYER = uiimage(app.GraficasGrid);
            app.EYER.ImageSource = fullfile(pathToMLAPP, 'EYE-HORUS-L.svg');
            app.EYEL = uiimage(app.GraficasGrid);
            app.EYEL.ImageSource = fullfile(pathToMLAPP, 'EYE-HORUS-R.svg');
            app.AnalizarMedidasHTML = uihtml(app.GraficasGrid);
            app.AnalizarMedidasHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.BackGraficasHTML = uihtml(app.GraficasGrid);
            app.BackGraficasHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.FSLabel = uilabel(app.GraficasGrid);
            app.FSLabel.Text = 'Label3';
            app.StartStopHTML = uihtml(app.GraficasGrid);
            app.StartStopHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.ExpandedViewScreen = uipanel(app.UIFigure);
            app.ExpandedViewGrid = uigridlayout(app.ExpandedViewScreen);
            app.ExpandedPlot = uiaxes(app.ExpandedViewGrid);
            title(app.ExpandedPlot, 'Signal View')
            xlabel(app.ExpandedPlot, 'X')
            ylabel(app.ExpandedPlot, 'Y')
            zlabel(app.ExpandedPlot, 'Z')
            colormap(app.ExpandedPlot, 'winter')
            app.BackExpandedViewHTML = uihtml(app.ExpandedViewGrid);
            app.BackExpandedViewHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.SignalViewLabel = uilabel(app.ExpandedViewGrid);
            app.SignalViewLabel.Text = 'Signal View';
            app.SignalViewSlider = uislider(app.ExpandedViewGrid);
            app.SignalViewSlider.Limits = [0 1];
            app.SignalViewSlider.MajorTicks = [];
            app.SignalViewSlider.ValueChangedFcn = createCallbackFcn(app, @SliderMoving, true);
            app.SignalViewSlider.ValueChangingFcn = createCallbackFcn(app, @SliderMoving, true);
            app.SignalViewSlider.MinorTicks = [0 0.005 0.01 0.015 0.02 0.025 0.03 0.035 0.04 0.045 0.05 0.055 0.06 0.065 0.07 0.075 0.08 0.085 0.09 0.095 0.1 0.105 0.11 0.115 0.12 0.125 0.13 0.135 0.14 0.145];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
            app.AnalisisSignalsScreen = uipanel(app.UIFigure);
            app.AnalisisSignalsGrid = uigridlayout(app.AnalisisSignalsScreen);
            app.RHorizontalAxis = uiaxes(app.AnalisisSignalsGrid);
            title(app.RHorizontalAxis, 'R Horizontal')
            zlabel(app.RHorizontalAxis, 'Z')
            app.RVerticalAxis = uiaxes(app.AnalisisSignalsGrid);
            title(app.RVerticalAxis, 'R Vertical')
            zlabel(app.RVerticalAxis, 'Z')
            app.LHorizontalAxis = uiaxes(app.AnalisisSignalsGrid);
            title(app.LHorizontalAxis, 'L Horizontal')
            zlabel(app.LHorizontalAxis, 'Z')
            app.LVerticalAxis = uiaxes(app.AnalisisSignalsGrid);
            title(app.LVerticalAxis, 'L Vertical')
            zlabel(app.LVerticalAxis, 'Z')
            app.EYER2 = uiimage(app.AnalisisSignalsGrid);
            app.EYER2.ImageSource = fullfile(pathToMLAPP, 'EYE-HORUS-L.svg');
            app.EYEL2 = uiimage(app.AnalisisSignalsGrid);
            app.EYEL2.ImageSource = fullfile(pathToMLAPP, 'EYE-HORUS-R.svg');
            app.HorizontalLExpandedHTML = uihtml(app.AnalisisSignalsGrid);
            app.HorizontalLExpandedHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.HorizontalRExpandedHTML = uihtml(app.AnalisisSignalsGrid);
            app.HorizontalRExpandedHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.VerticalLExpandedHTML = uihtml(app.AnalisisSignalsGrid);
            app.VerticalLExpandedHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.VerticalRExpandedHTML = uihtml(app.AnalisisSignalsGrid);
            app.VerticalRExpandedHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.ResultadosAnalisisHTML = uihtml(app.AnalisisSignalsGrid);
            app.ResultadosAnalisisHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.BackAnalisisSignalsHTML = uihtml(app.AnalisisSignalsGrid);
            app.BackAnalisisSignalsHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.ConectScreen = uipanel(app.UIFigure);
            app.ConectGrid = uigridlayout(app.ConectScreen);
            app.ShowHiddenWifisCheckBox = uicheckbox(app.ConectGrid);
            app.ShowHiddenWifisCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowHiddenWifisClicked, true);
            app.ShowHiddenWifisCheckBox.Text = 'Enseñar Redes Escondidas';
            app.WifiStatusHTML = uihtml(app.ConectGrid);
            app.DevicesListHTML = uihtml(app.ConectGrid);
            app.DevicesListHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @DevicesListClicked, true);
            app.LogoHorusHTML = uihtml(app.ConectGrid);
            app.BuscarDevicesHTML = uihtml(app.ConectGrid);
            app.BuscarDevicesHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BuscarDevicesClicked, true);
            app.TomarMedidasHTML = uihtml(app.ConectGrid);
            app.TomarMedidasHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.BackConectHTML = uihtml(app.ConectGrid);
            app.BackConectHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.AnalisisScreen = uipanel(app.UIFigure);
            app.AnalisisGrid = uigridlayout(app.AnalisisScreen);
            app.ANALISISEOGLabel = uilabel(app.AnalisisGrid);
            app.ANALISISEOGLabel.Text = '            ANÁLISIS EOG';
            app.Image_2 = uiimage(app.AnalisisGrid);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'PERSON-ICON-WHITE.png');
            app.BackAnalisisHTML = uihtml(app.AnalisisGrid);
            app.BackAnalisisHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.AnalisisEventosHTML = uihtml(app.AnalisisGrid);
            app.AnalisisEventosHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.FechaLabel = uilabel(app.AnalisisGrid);
            app.FechaLabel.Text = 'Fecha:';
            app.DuracionLabel = uilabel(app.AnalisisGrid);
            app.DuracionLabel.Text = 'Duracion:';
            app.CorrelacindeSpearmanPanel = uipanel(app.AnalisisGrid);
            app.CorrelacindeSpearmanPanel.Title = ' Correlación de Spearman';
            app.GridLayout = uigridlayout(app.CorrelacindeSpearmanPanel);
            app.CorrAxes = uiaxes(app.GridLayout);
            xlabel(app.CorrAxes, 'Amplitud (mV)')
            ylabel(app.CorrAxes, 'Vel. pico (mV/s)')
            zlabel(app.CorrAxes, 'Z')
            app.RresultLBL = uilabel(app.GridLayout);
            app.RresultLBL.Text = 'R=';
            app.TasadeparpadeoPanel = uipanel(app.AnalisisGrid);
            app.TasadeparpadeoPanel.Title = 'Tasa de parpadeo';
            app.GridLayout_2 = uigridlayout(app.TasadeparpadeoPanel);
            app.Gauge = uigauge(app.GridLayout_2, 'semicircular');
            app.Gauge.Limits = [0 40];
            app.Gauge.MajorTicks = [0 4 8 14 20 25 30 35 40];
            app.Gauge.MajorTickLabels = {'0', '4', '8', '14', '20', '25', '30', '35', '40'};
            app.Gauge.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40];
            app.ParpadeosminLabel = uilabel(app.GridLayout_2);
            app.ParpadeosminLabel.Text = 'Parpadeos/min';
            app.ParpadeostotalesLabel = uilabel(app.GridLayout_2);
            app.ParpadeostotalesLabel.Text = 'Parpadeos totales';
            app.RateParpadeosLBL = uilabel(app.GridLayout_2);
            app.RateParpadeosLBL.Text = 'Label8';
            app.TotalParpadeosLBL = uilabel(app.GridLayout_2);
            app.TotalParpadeosLBL.Text = 'Label9';
            app.MetricasclavePanel = uipanel(app.AnalisisGrid);
            app.MetricasclavePanel.Title = 'Metricas clave';
            app.GridLayout_3 = uigridlayout(app.MetricasclavePanel);
            app.SacadastotalesButton = uibutton(app.GridLayout_3, 'push');
            app.SacadastotalesButton.Text = 'Sacadas totales';
            app.DurationmediaButton = uibutton(app.GridLayout_3, 'push');
            app.DurationmediaButton.Text = 'Duración media';
            app.ParpadeosButton = uibutton(app.GridLayout_3, 'push');
            app.ParpadeosButton.Text = 'Parpadeos';
            app.FijacionMediaButton = uibutton(app.GridLayout_3, 'push');
            app.FijacionMediaButton.Text = 'Fijacion Media';
            app.AsimetriaButton = uibutton(app.GridLayout_3, 'push');
            app.AsimetriaButton.Text = 'Asimetria';
            app.CorrelationButton = uibutton(app.GridLayout_3, 'push');
            app.CorrelationButton.Text = 'Correlación';
            app.AsimetriaIzquierdaDerechaPanel = uipanel(app.AnalisisGrid);
            app.AsimetriaIzquierdaDerechaPanel.Title = 'Asimetria Izquierda/Derecha';
            app.GridLayout_4 = uigridlayout(app.AsimetriaIzquierdaDerechaPanel);
            app.AsimetriaAxes = uiaxes(app.GridLayout_4);
            zlabel(app.AsimetriaAxes, 'Z')
            app.AsimetriaAxes.XLim = [-1.1 1.1];
            app.AsimetriaAxes.YLim = [-1 1];
            app.AsimetriaAxes.YTick = [];
            app.ObservacionesPanel = uipanel(app.AnalisisGrid);
            app.ObservacionesPanel.Title = 'Observaciones';
            app.GridLayout_5 = uigridlayout(app.ObservacionesPanel);
            app.ObsHTML = uihtml(app.GridLayout_5);
            app.FrecuenciaLabel = uilabel(app.AnalisisGrid);
            app.FrecuenciaLabel.Text = 'Frecuencia:';
            app.TimeLBL = uilabel(app.AnalisisGrid);
            app.TimeLBL.Text = 'Label5';
            app.DurationLBL = uilabel(app.AnalisisGrid);
            app.DurationLBL.Text = 'Label6';
            app.FrequencyLBL = uilabel(app.AnalisisGrid);
            app.FrequencyLBL.Text = 'Label7';
            app.ExportHTML = uihtml(app.AnalisisGrid);
            app.ExportHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ExportClicked, true);
            app.MainScreen = uipanel(app.UIFigure);
            app.MainGrid = uigridlayout(app.MainScreen);
            app.SignInLBL = uilabel(app.MainGrid);
            app.SignInLBL.Text = 'Inicia Sesión';
            app.LOGOSEPA = uiimage(app.MainGrid);
            app.LOGOSEPA.ImageSource = fullfile(pathToMLAPP, 'LOGO-SEPA-EXPANDED.png');
            app.SignInStateLBL = uilabel(app.MainGrid);
            app.SignInStateLBL.Text = 'Sesion Iniciada Correctamente';
            app.CrearCuentaLink = uihyperlink(app.MainGrid);
            app.CrearCuentaLink.HyperlinkClickedFcn = createCallbackFcn(app, @CrearCuentaClicked, true);
            app.CrearCuentaLink.Text = 'Crear Cuenta';
            app.AccederHTML = uihtml(app.MainGrid);
            app.AccederHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.ConectionCableCheckBox = uicheckbox(app.MainGrid);
            app.ConectionCableCheckBox.Text = 'Conexión por cable';
            app.UserNameHTML = uihtml(app.MainGrid);
            app.PasswordHTML = uihtml(app.MainGrid);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HORUS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end